classdef BatchFileDataFull_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SaveDataLocationPanel       matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        SavePathLabel               matlab.ui.control.Label
        ShowSavePathLabel           matlab.ui.control.Label
        SelectLocationButton        matlab.ui.control.Button
        DoneButton                  matlab.ui.control.Button
        FileSelectionPanel          matlab.ui.container.Panel
        SubFoldersCheck             matlab.ui.control.CheckBox
        ParametersFileLabel         matlab.ui.control.Label
        ShowFileNameLabel           matlab.ui.control.Label
        SelectParametersFileButton  matlab.ui.control.Button
        BatchFolderLabel            matlab.ui.control.Label
        ShowFolderNameLabel         matlab.ui.control.Label
        SelectFoldertoBatchProcessButton  matlab.ui.control.Button
    end

    % Properties added by user
    properties (Access = public)
        BatchFolder string = ""
        SubFolders = false
        SelectedPathName
        SelectedFileName
        SavePath string = "" 
        Output
    end
    
    % Helper functions added by user
    methods (Access = private)
        
        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Value changed function: SubFoldersCheck
        function SubFoldersCheckValueChanged(app, event)
            Sub = app.SubFoldersCheck.Value;
            app.SubFolders = Sub;
        end

        % Button pushed function: SelectFoldertoBatchProcessButton
        function SelectFoldertoBatchProcessButtonPushed(app, event)
            folder = uigetdir(pwd, 'Select Folder to Batch Process');
            if folder ~= 0
                app.BatchFolder = folder;
                app.ShowFolderNameLabel.Text = folder;
            else
                uialert(app.UIFigure, 'No folder selected.', 'Selection Cancelled');
            end
        end

        % Button pushed function: SelectParametersFileButton
        function SelectParametersFileButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.mat'});
            if isequal(fileName, 0)
                return;
            end
            app.SelectedFileName = fileName;
            app.SelectedPathName = pathName;
            app.ShowFileNameLabel.Text = fileName;
        end

        % Button pushed function: SelectLocationButton
        function SelectLocationButtonPushed(app, event)
            folder = uigetdir(pwd, 'Select Folder to Save Data');
            if folder ~= 0
                app.SavePath = folder;
                app.ShowSavePathLabel.Text = folder;
            else
                uialert(app.UIFigure, 'No folder selected.', 'Selection Cancelled');
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if isempty(app.BatchFolder) || strlength(strtrim(app.BatchFolder)) == 0
                uialert(app.UIFigure, 'You must select a valid folder to proceed.', ...
                        'Invalid Folder');
                return;
            end

             if isempty(app.SavePath) || strlength(strtrim(app.SavePath)) == 0
                uialert(app.UIFigure, 'You must select a valid save path to proceed.', ...
                        'Invalid Save Path');
                return;
            end
          
            assignin('base', 'BatchFolder', app.BatchFolder);
            assignin('base', 'SubFolders', app.SubFolders);
            assignin('base', 'ParametersFile', app.SelectedFileName);
            assignin('base', 'ParametersPath', app.SelectedPathName);
            assignin('base', 'SaveBatchPath', app.SavePath)

            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileSelectionPanel = uipanel(app.UIFigure);
            app.FileSelectionPanel.Title = 'File Selection';
            app.SelectFoldertoBatchProcessButton = uibutton(app.FileSelectionPanel, 'push');
            app.SelectFoldertoBatchProcessButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFoldertoBatchProcessButtonPushed, true);
            app.SelectFoldertoBatchProcessButton.Text = 'Select Folder to Batch Process';
            app.ShowFolderNameLabel = uilabel(app.FileSelectionPanel);
            app.ShowFolderNameLabel.Text = '';
            app.BatchFolderLabel = uilabel(app.FileSelectionPanel);
            app.BatchFolderLabel.Text = 'Batch Folder:';
            app.SelectParametersFileButton = uibutton(app.FileSelectionPanel, 'push');
            app.SelectParametersFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectParametersFileButtonPushed, true);
            app.SelectParametersFileButton.Text = 'Select Parameters File';
            app.ShowFileNameLabel = uilabel(app.FileSelectionPanel);
            app.ShowFileNameLabel.Text = '';
            app.ParametersFileLabel = uilabel(app.FileSelectionPanel);
            app.ParametersFileLabel.Text = 'Parameters File:';
            app.SubFoldersCheck = uicheckbox(app.FileSelectionPanel);
            app.SubFoldersCheck.ValueChangedFcn = createCallbackFcn(app, @SubFoldersCheckValueChanged, true);
            app.SubFoldersCheck.Text = 'Include files in all subfolders';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SaveDataLocationPanel = uipanel(app.UIFigure);
            app.SaveDataLocationPanel.Title = 'Save Data Location?';
            app.SelectLocationButton = uibutton(app.SaveDataLocationPanel, 'push');
            app.SelectLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationButtonPushed, true);
            app.SelectLocationButton.Text = 'Select Location';
            app.ShowSavePathLabel = uilabel(app.SaveDataLocationPanel);
            app.ShowSavePathLabel.Text = '';
            app.SavePathLabel = uilabel(app.SaveDataLocationPanel);
            app.SavePathLabel.Text = 'Save Path:';
            app.TextArea = uitextarea(app.SaveDataLocationPanel);
            app.TextArea.Value = {'Select the location in which you would like data to be saved. A new folder will be created with the name of the image file you selected above.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatchFileDataFull_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end