classdef BatchFileDataPartial_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MicrogliamatFolderSelectionPanel  matlab.ui.container.Panel
        ShowFolderNameLabel_2         matlab.ui.control.Label
        MicrogliamatFilesFolderLabel  matlab.ui.control.Label
        SelectFullMgButton            matlab.ui.control.Button
        TextArea_2                    matlab.ui.control.TextArea
        SaveDataLocationPanel         matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        SavePathLabel                 matlab.ui.control.Label
        ShowSavePathLabel             matlab.ui.control.Label
        SelectLocationButton          matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        BatchFolderSelectionPanel     matlab.ui.container.Panel
        SubFoldersCheck               matlab.ui.control.CheckBox
        ParametersFileLabel           matlab.ui.control.Label
        ShowFileNameLabel             matlab.ui.control.Label
        SelectParametersFileButton    matlab.ui.control.Button
        BatchFolderLabel              matlab.ui.control.Label
        ShowFolderNameLabel           matlab.ui.control.Label
        SelectFoldertoBatchProcessButton  matlab.ui.control.Button
    end

    % Properties added by user
    properties (Access = public)
        BatchFolder string = ""
        FullMgFolder string = ""
        SubFolders = false
        SelectedPathName
        SelectedFileName
        SavePath string = "" 
        Output
    end
    
    % Helper functions added by user
    methods (Access = private)
        
        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Value changed function: SubFoldersCheck
        function SubFoldersCheckValueChanged(app, event)
            Sub = app.SubFoldersCheck.Value;
            app.SubFolders = Sub;
        end

        % Button pushed function: SelectFoldertoBatchProcessButton
        function SelectFoldertoBatchProcessButtonPushed(app, event)
            folder = uigetdir(pwd, 'Select Folder to Batch Process');
            if folder ~= 0
                app.BatchFolder = folder;
                app.ShowFolderNameLabel.Text = folder;
            else
                uialert(app.UIFigure, 'No folder selected.', 'Selection Cancelled');
            end
        end

        % Button pushed function: SelectFullMgButton
        function SelectFullMgButtonPushed(app, event)
            folder2 = uigetdir(pwd, 'Select Folder tcontaining FullMg .mat files');
            if folder2 ~= 0
                app.FullMgFolder = folder2;
                app.ShowFolderNameLabel_2.Text = folder2;
            else
                uialert(app.UIFigure, 'No folder selected.', 'Selection Cancelled');
            end
        end

        % Button pushed function: SelectParametersFileButton
        function SelectParametersFileButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.mat'});
            if isequal(fileName, 0)
                return;
            end
            app.SelectedFileName = fileName;
            app.SelectedPathName = pathName;
            app.ShowFileNameLabel.Text = fileName;
        end

        % Button pushed function: SelectLocationButton
        function SelectLocationButtonPushed(app, event)
            folder = uigetdir(pwd, 'Select Folder to Save Data');
            if folder ~= 0
                app.SavePath = folder;
                app.ShowSavePathLabel.Text = folder;
            else
                uialert(app.UIFigure, 'No folder selected.', 'Selection Cancelled');
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if isempty(app.BatchFolder) || strlength(strtrim(app.BatchFolder)) == 0
                uialert(app.UIFigure, 'You must select a valid folder of image files to proceed.', ...
                        'Invalid Folder');
                return;
            end

            if isempty(app.BatchFolder) || strlength(strtrim(app.BatchFolder)) == 0
                uialert(app.UIFigure, 'You must select a valid folder of FullMg mat files to proceed.', ...
                        'Invalid Folder');
                return;
            end

             if isempty(app.SavePath) || strlength(strtrim(app.SavePath)) == 0
                uialert(app.UIFigure, 'You must select a valid save path to proceed.', ...
                        'Invalid Save Path');
                return;
            end
          
            assignin('base', 'BatchFolder', app.BatchFolder);
            assignin('base', 'FullMgFolder', app.FullMgFolder);
            assignin('base', 'SubFolders', app.SubFolders);
            assignin('base', 'ParametersFile', app.SelectedFileName);
            assignin('base', 'ParametersPath', app.SelectedPathName);
            assignin('base', 'SaveBatchPath', app.SavePath)

            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BatchFolderSelectionPanel = uipanel(app.UIFigure);
            app.BatchFolderSelectionPanel.Title = 'Batch Folder Selection';
            app.SelectFoldertoBatchProcessButton = uibutton(app.BatchFolderSelectionPanel, 'push');
            app.SelectFoldertoBatchProcessButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFoldertoBatchProcessButtonPushed, true);
            app.SelectFoldertoBatchProcessButton.Text = 'Select Folder to Batch Process';
            app.ShowFolderNameLabel = uilabel(app.BatchFolderSelectionPanel);
            app.ShowFolderNameLabel.Text = '';
            app.BatchFolderLabel = uilabel(app.BatchFolderSelectionPanel);
            app.BatchFolderLabel.Text = 'Batch Folder:';
            app.SelectParametersFileButton = uibutton(app.BatchFolderSelectionPanel, 'push');
            app.SelectParametersFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectParametersFileButtonPushed, true);
            app.SelectParametersFileButton.Text = 'Select Parameters File';
            app.ShowFileNameLabel = uilabel(app.BatchFolderSelectionPanel);
            app.ShowFileNameLabel.Text = '';
            app.ParametersFileLabel = uilabel(app.BatchFolderSelectionPanel);
            app.ParametersFileLabel.Text = 'Parameters File:';
            app.SubFoldersCheck = uicheckbox(app.BatchFolderSelectionPanel);
            app.SubFoldersCheck.ValueChangedFcn = createCallbackFcn(app, @SubFoldersCheckValueChanged, true);
            app.SubFoldersCheck.Text = 'Include files in all subfolders';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SaveDataLocationPanel = uipanel(app.UIFigure);
            app.SaveDataLocationPanel.Title = 'Save Data Location?';
            app.SelectLocationButton = uibutton(app.SaveDataLocationPanel, 'push');
            app.SelectLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationButtonPushed, true);
            app.SelectLocationButton.Text = 'Select Location';
            app.ShowSavePathLabel = uilabel(app.SaveDataLocationPanel);
            app.ShowSavePathLabel.Text = '';
            app.SavePathLabel = uilabel(app.SaveDataLocationPanel);
            app.SavePathLabel.Text = 'Save Path:';
            app.TextArea = uitextarea(app.SaveDataLocationPanel);
            app.TextArea.Value = {'Select the location in which you would like data to be saved. A new folder will be created with the name of the image file you selected above.'};
            app.MicrogliamatFolderSelectionPanel = uipanel(app.UIFigure);
            app.MicrogliamatFolderSelectionPanel.Title = 'Microglia .mat Folder Selection';
            app.TextArea_2 = uitextarea(app.MicrogliamatFolderSelectionPanel);
            app.TextArea_2.Value = {'Note: all .mat file names must begin with ''FullMg_'' followed by the image file name. All FullMg .mat files must be saved in the same folder.'};
            app.SelectFullMgButton = uibutton(app.MicrogliamatFolderSelectionPanel, 'push');
            app.SelectFullMgButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFullMgButtonPushed, true);
            app.SelectFullMgButton.Text = 'Select Folder to Containing Microglia .mat files';
            app.MicrogliamatFilesFolderLabel = uilabel(app.MicrogliamatFolderSelectionPanel);
            app.MicrogliamatFilesFolderLabel.Text = 'Microglia .mat Files Folder:';
            app.ShowFolderNameLabel_2 = uilabel(app.MicrogliamatFolderSelectionPanel);
            app.ShowFolderNameLabel_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatchFileDataPartial_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end