classdef CellSizeCutoffGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectedObjectLabel         matlab.ui.control.Label
        AllIdentifiedObjectsLabel   matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ListBox                     matlab.ui.control.ListBox
        ObjectsArrangedbySizeLabel  matlab.ui.control.Label
        SelectButton                matlab.ui.control.Button
        Title                       matlab.ui.container.Panel
        Instructions                matlab.ui.control.Label
        SelectedObject              matlab.ui.control.UIAxes
        AllObjects                  matlab.ui.control.UIAxes
    end

    % Properties added by user
    properties (Access = public)
        fullimg
        cmap
        CellSizeCutoff
        ShowObjImg
        allObjs
        ObjectList
        udObjectList
        numObj
        ConnectedComponents
        Output
        voxscale
        zs
        s
    end


    % Helper functions added by user
    methods (Access = public)
        % function setup(app, SegInput)
        %     app.udObjectList = SegInput.udObjectList;
        %     app.ObjectList = SegInput.ObjectList;
        %     app.allObjs = SegInput.allObjs;
        %     app.ConnectedComponents = SegInput.ConnectedComponents;
        %     app.numObj = SegInput.numObj;
        %     app.voxscale = SegInput.voxscale;
        %     app.s = SegInput.s;
        %     app.zs = SegInput.zs;
        %
        %     % Plot all objects
        %     num = 1:3:(3*app.numObj+1);
        %     app.fullimg = ones(app.s(1), app.s(2));
        %     progbar = waitbar(0, 'Plotting...');
        %
        %     % Build full image with colored objects
        %     for i = 1:app.numObj
        %         waitbar(i/app.numObj, progbar);
        %
        %         % Get object mask in 3D
        %         j = app.udObjectList(i, 2); % Index into PixelIdxList
        %         ex = zeros(app.s(1), app.s(2), app.zs);
        %         ex(app.ConnectedComponents.PixelIdxList{1, j}) = 1;
        %         flatex = sum(ex, 3);  % Flatten in Z
        %
        %         % Create outline mask
        %         OutlineImage = zeros(app.s(1), app.s(2));
        %         OutlineImage(flatex > 1) = 1;
        %         se = strel('diamond', 4);
        %         Outline = imdilate(OutlineImage, se);
        %
        %         % Add outline and object to full image
        %         app.fullimg(Outline == 1) = 1; % outline stays white
        %         app.fullimg(flatex > 1) = num(i+1); % colored object
        %     end
        %
        %     if isgraphics(progbar)
        %         close(progbar);
        %     end
        %
        %     % Create & apply colormap
        %     app.cmap = jet(max(app.fullimg(:)));
        %     app.cmap(1, :) = [0 0 0]; % Background is black
        %
        %     % Display image in UIAxes
        %     imagesc(app.AllObjects, app.fullimg);
        %     axis(app.AllObjects, 'image');
        %     colormap(app.AllObjects, app.cmap);
        %     colorbar(app.AllObjects, 'Ticks', [1, 3*app.numObj+1], ...
        %         'TickLabels', {'Small', 'Large'});
        %     app.AllObjects.Visible = 'off';
        %
        %     % Convert object sizes and populate listbox
        %     ObjectListInUnit = round(app.ObjectList(:,1) * app.voxscale);
        %     app.ListBox.Items = string(ObjectListInUnit(:, 1));    % Display sizes
        %     app.ListBox.ItemsData = 1:size(app.ObjectList, 1);     % Store row indices (1, 2, 3, ...)
        % end

        function setup(app, SegInput)
            app.udObjectList = SegInput.udObjectList;
            app.ObjectList = SegInput.ObjectList;
            app.allObjs = SegInput.allObjs;
            app.ConnectedComponents = SegInput.ConnectedComponents;
            app.numObj = SegInput.numObj;
            app.voxscale = SegInput.voxscale;
            app.s = SegInput.s;
            app.zs = SegInput.zs;

            % Plot all objects
            % num = 1:3:(3*app.numObj+1);
            % app.fullimg = ones(app.s(1), app.s(2));
            % progbar = waitbar(0, 'Plotting...');

            % Build full image with colored objects
            % for i = 1:app.numObj
            %     waitbar(i/app.numObj, progbar);
            %
            %     flatex = app.allObjs(:, :, i);
            %
            %     % Create outline mask
            %     OutlineImage = zeros(app.s(1), app.s(2));
            %     OutlineImage(flatex > 1) = 1;
            %     se = strel('diamond', 4);
            %     Outline = imdilate(OutlineImage, se);
            %
            %     % Add outline and object to full image
            %     app.fullimg(Outline == 1) = 1; % outline stays white
            %     app.fullimg(flatex > 1) = num(i+1); % colored object
            % end
            %
            % if isgraphics(progbar)
            %     close(progbar);
            % end

            num = 1:3:(3*app.numObj+1);
            app.fullimg = ones(app.s(1), app.s(2));
            progbar = waitbar(0, 'Plotting...');

            
            for i = 1:app.numObj
                waitbar(i/app.numObj, progbar);
            
                % Use udObjectList to get actual object index (smallest to largest)
                objectIdx = app.udObjectList(i, 2);
                flatex = app.allObjs(:, :, objectIdx);
            
                % Create outline mask
                OutlineImage = zeros(app.s(1), app.s(2));
                OutlineImage(flatex >= 1) = 1;
                se = strel('diamond', 4);
                Outline = imdilate(OutlineImage, se);
            
                % Add outline and object to full image
                app.fullimg(Outline == 1) = 1;
                app.fullimg(flatex >= 1) = num(i+1);  % Small objects get low colors, large get high
            end
            if isgraphics(progbar)
                close(progbar);
            end
            % Create & apply colormaps
            app.cmap = jet(max(app.fullimg(:)));
            app.cmap(1, :) = [0 0 0]; % Background is black

            % Display image in UIAxes
            imagesc(app.AllObjects, app.fullimg);
            axis(app.AllObjects, 'image');
            colormap(app.AllObjects, app.cmap);
            colorbar(app.AllObjects, 'Ticks', [1, 3*app.numObj+1], ...
                'TickLabels', {'Small', 'Large'});
            app.AllObjects.Visible = 'off';

            % Convert object sizes and populate listbox
            ObjectListInUnit = round(app.ObjectList(:,1) * app.voxscale);
            app.ListBox.Items = string(ObjectListInUnit(:, 1));    % Display sizes
            app.ListBox.ItemsData = 1:size(app.ObjectList, 1);     % Store row indices (1, 2, 3, ...)
        end

        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % % Get the selected row index
            % rowIndex = app.ListBox.Value;
            %
            % % Get the index into PixelIdxList
            % objectIdx = app.ObjectList(rowIndex, 2);
            % % Display the object
            % imshow(app.allObjs(:, :, objectIdx), 'Parent', app.SelectedObject);
            % axis(app.SelectedObject, 'image');
            % colormap(app.SelectedObject, app.cmap);
            %
            % % Store the object size as your cutoff
            % app.CellSizeCutoff = app.ObjectList(rowIndex, 1);
            
            rowIndex = app.ListBox.Value;
            objectIdx = app.ObjectList(rowIndex, 2);
            flatex = app.allObjs(:, :, objectIdx);
            
            % Find what color this object has in fullimg
            mask = (flatex >= 1);
            colorValues = app.fullimg(mask);
            colorValue = mode(colorValues(colorValues > 1));  % Most common non-background value
            
            % Create display image
            displayImg = ones(app.s(1), app.s(2));  
            displayImg(mask) = colorValue;
            
            % Display with matching colormap and limits
            imagesc(app.SelectedObject, displayImg);
            axis(app.SelectedObject, 'image');
            colormap(app.SelectedObject, app.cmap);
            clim(app.SelectedObject, [1, max(app.fullimg(:))]);
            app.SelectedObject.Visible = 'off';
            
            app.CellSizeCutoff = app.ObjectList(rowIndex, 1);

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            assignin('base', 'fullimg', app.fullimg);
            assignin('base', 'CellSizeCutoff', app.CellSizeCutoff);
            assignin('base', 'ShowObjImg', app.ShowObjImg);

            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AllObjects = uiaxes(app.UIFigure);
            app.AllObjects.XTick = [];
            app.AllObjects.YTick = [];
            app.AllObjects.ZTick = [];
            app.SelectedObject = uiaxes(app.UIFigure);
            app.SelectedObject.XTick = [];
            app.SelectedObject.YTick = [];
            app.SelectedObject.ZTick = [];
            app.Title = uipanel(app.UIFigure);
            app.Title.Title = 'Select the Largest Single Cell';
            app.Instructions = uilabel(app.Title);
            app.Instructions.Text = {'1) The list on the left shows the size of each object in um^3. Use this to investigate each object. Choose the largest object that is a single cell. '; '2) When you are happy with your choice, click the "Select" button. Any object larger than (but not including) your selected value will be segmented into multiple cells in the next step.'};
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ObjectsArrangedbySizeLabel = uilabel(app.UIFigure);
            app.ObjectsArrangedbySizeLabel.Text = 'Objects Arranged by Size';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Label = uilabel(app.UIFigure);
            app.AllIdentifiedObjectsLabel = uilabel(app.UIFigure);
            app.AllIdentifiedObjectsLabel.Text = 'All Identified Objects';
            app.SelectedObjectLabel = uilabel(app.UIFigure);
            app.SelectedObjectLabel.Text = 'Selected Object';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellSizeCutoffGUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end