classdef ColocCompObjects_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DoneExitButton                 matlab.ui.control.Button
        SkipButton                     matlab.ui.control.Button
        CombineChannelsIntoNewStructurePanel  matlab.ui.container.Panel
        CombineQ                       matlab.ui.container.ButtonGroup
        Both                           matlab.ui.control.ToggleButton
        OverlapOnly                    matlab.ui.control.ToggleButton
        FuseAll                        matlab.ui.control.ToggleButton
        OverlapOnlyImg                 matlab.ui.control.UIAxes
        FuseAllImg                     matlab.ui.control.UIAxes
        CreatedStructuresListBox       matlab.ui.control.ListBox
        CreatedStructuresListBoxLabel  matlab.ui.control.Label
        DoneCreateButton               matlab.ui.control.Button
        NewStructureName               matlab.ui.control.EditField
        NewStructureNameexexcitatorysynapsesLabel  matlab.ui.control.Label
        SelectChannelsListBox          matlab.ui.control.ListBox
        SelectChannelsListBoxLabel     matlab.ui.control.Label
        MultiColoc                     matlab.ui.container.Panel
        Instructions                   matlab.ui.control.Label
    end

    properties (Access = public)
        Output
        ChanNames
        InputImgs
        ColocComp struct = struct()
        BatchColocComp struct = struct()
    end

    methods (Access = public)
        function setup(app, ColocCompInput)
            app.InputImgs = ColocCompInput.chans;
            app.ChanNames = fieldnames(app.InputImgs);
            app.SelectChannelsListBox.Items = app.ChanNames;

            % Update with example images
            Fuseimg = imread('FuseAllEg.png');
            imshow(Fuseimg, 'Parent', app.FuseAllImg);
            axis(app.FuseAllImg, 'image');
            
            Overlapimg = imread('OverlapOnlyEg.png');
            imshow(Overlapimg, 'Parent', app.OverlapOnlyImg);
            axis(app.OverlapOnlyImg, 'image');
        end

        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
        %    app.BatchColocComp = struct();
         %   assignin('base', 'BatchColocComp', app.BatchColocComp);
            app.Output = true;
        end

        % Button pushed function: DoneCreateButton
        function DoneCreateButtonPushed(app, event)
            if ~isvarname(app.NewStructureName.Value)
                uialert(app.UIFigure, ...
                    'New structure names must be unique and start with a letter and contain only letters, digits, and underscores (no spaces or special characters).', ...
                    'Invalid Structure Name');
                return;
            end
            % Save selected channels with new structure name and save
            selectedChannels = app.SelectChannelsListBox.Value;
            if numel(selectedChannels) < 2
                uialert(app.UIFigure, ...
                    'You must select at least two channels from the list in "Select Channels".', ...
                    'Insufficient Selection');
                return;
            end
            fieldName = app.NewStructureName.Value;
            combineChoice = app.CombineQ.SelectedObject.Tag;
            groupData = struct();
            groupData.Channels = struct();
            for i = 1:numel(selectedChannels)
                chName = selectedChannels{i};
                groupData.Channels.(chName) = app.InputImgs.(chName);
            end
            groupData.Combine = combineChoice;
            app.ColocComp.(fieldName) = groupData;

            % Collect names in struct (for batch)
            batchData = struct();
            batchData.Channels = struct();
            for i = 1:numel(selectedChannels)
                chName = selectedChannels{i};
                batchData.Channels.(chName) = chName;
            end
            batchData.Combine = combineChoice;
            app.BatchColocComp.(fieldName) = batchData;  

            % Pass created structure name to list box
            newName = app.NewStructureName.Value;
            currentItems = app.CreatedStructuresListBox.Items;
            currentItems = currentItems(:);
            newItem = {newName};
            app.CreatedStructuresListBox.Items = [currentItems; newItem];

            % Reset input fields
            app.NewStructureName.Value = '';
            app.SelectChannelsListBox.Value = {};
        end

        % Button pushed function: DoneExitButton
        function DoneExitButtonPushed(app, event)
            assignin('base', 'ColocComp', app.ColocComp);
            assignin('base', 'BatchColocComp', app.BatchColocComp);
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MultiColoc = uipanel(app.UIFigure);
            app.MultiColoc.Title = 'Combine Channel Signal to Form New Structures?';
            app.Instructions = uilabel(app.MultiColoc);
            app.Instructions.Text = 'You might be using 2 or more markers for the same structure (e.g., PSD95 and Shank3 for excitatory synapses) and that you want to combine in specific ways. Select channel combinations you want to colocalize as single structures and assign this new structure a unique name. You have the option to combine selected structures into a composite (upper example), or keep only those voxels that overlap between them (lower example), or you can do both (each will be saved as seperate images). As before, your original channels will be retained in addition to any filtered channels and composite structures you create. ';
            app.CombineChannelsIntoNewStructurePanel = uipanel(app.UIFigure);
            app.CombineChannelsIntoNewStructurePanel.Title = 'Combine Channels Into New Structure';
            app.SelectChannelsListBoxLabel = uilabel(app.CombineChannelsIntoNewStructurePanel);
            app.SelectChannelsListBoxLabel.Text = 'Select Channels';
            app.SelectChannelsListBox = uilistbox(app.CombineChannelsIntoNewStructurePanel);
            app.SelectChannelsListBox.Items = {};
            app.SelectChannelsListBox.Multiselect = 'on';
            app.SelectChannelsListBox.Value = {};
            app.NewStructureNameexexcitatorysynapsesLabel = uilabel(app.CombineChannelsIntoNewStructurePanel);
            app.NewStructureNameexexcitatorysynapsesLabel.Text = {'New Structure Name '; '(ex. excitatory_synapses)'};
            app.NewStructureName = uieditfield(app.CombineChannelsIntoNewStructurePanel, 'text');
            app.DoneCreateButton = uibutton(app.CombineChannelsIntoNewStructurePanel, 'push');
            app.DoneCreateButton.ButtonPushedFcn = createCallbackFcn(app, @DoneCreateButtonPushed, true);
            app.DoneCreateButton.Text = 'Add New Structure';
            app.CreatedStructuresListBoxLabel = uilabel(app.CombineChannelsIntoNewStructurePanel);
            app.CreatedStructuresListBoxLabel.Text = 'Created Structures';
            app.CreatedStructuresListBox = uilistbox(app.CombineChannelsIntoNewStructurePanel);
            app.CreatedStructuresListBox.Items = {''};
            app.CreatedStructuresListBox.Value = {};
            app.CombineQ = uibuttongroup(app.CombineChannelsIntoNewStructurePanel);
            app.CombineQ.Title = 'How do you want to combine?';
            app.FuseAllImg = uiaxes(app.CombineQ);
            app.FuseAllImg.XTick = [];
            app.FuseAllImg.YTick = [];
            app.FuseAllImg.ZTick = [];
            app.OverlapOnlyImg = uiaxes(app.CombineQ);
            app.OverlapOnlyImg.XTick = [];
            app.OverlapOnlyImg.YTick = [];
            app.OverlapOnlyImg.ZTick = [];
            app.FuseAll = uitogglebutton(app.CombineQ);
            app.FuseAll.Tag = 'union';
            app.FuseAll.Text = 'Combine all voxels from overlapping objects into composite object (union)';
            app.OverlapOnly = uitogglebutton(app.CombineQ);
            app.OverlapOnly.Tag = 'intersect';
            app.OverlapOnly.Text = 'Keep only voxels that overlap (intersect)';
            app.OverlapOnly.Value = true;
            app.Both = uitogglebutton(app.CombineQ);
            app.Both.Tag = 'both';
            app.Both.Text = 'Both';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip this step';
            app.DoneExitButton = uibutton(app.UIFigure, 'push');
            app.DoneExitButton.ButtonPushedFcn = createCallbackFcn(app, @DoneExitButtonPushed, true);
            app.DoneExitButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocCompObjects_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end