classdef ColocFilterExclusive_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ExclusionFilter           matlab.ui.container.Panel
        FilterMode                matlab.ui.container.ButtonGroup
        Both                      matlab.ui.control.ToggleButton
        Objects                   matlab.ui.control.ToggleButton
        Voxels                    matlab.ui.control.ToggleButton
        ExcludeStructuresListBox  matlab.ui.control.ListBox
        FilteredChannelsLabel     matlab.ui.control.Label
        ExclusionAddButton        matlab.ui.control.Button
        ExclusionName             matlab.ui.control.EditField
        FilteredStructureNameexChannelA_noAFLabel  matlab.ui.control.Label
        IfBDropDownExcl           matlab.ui.control.DropDown
        IfBDropDown_2Label        matlab.ui.control.Label
        ExcludeADropDown          matlab.ui.control.DropDown
        ExcludeADropDownLabel     matlab.ui.control.Label
        ExclusionVoxImg           matlab.ui.control.UIAxes
        ExclusionImg              matlab.ui.control.UIAxes
        FilterColoc               matlab.ui.container.Panel
        FilterInstructions        matlab.ui.control.Label
        SkipButton                matlab.ui.control.Button
        DoneExitButton            matlab.ui.control.Button
    end

    properties (Access = public)
        Output
        ChanNames
        InputImgs
        ColocExclude struct = struct()
        ColocExcludeBatch struct = struct()
    end

    methods (Access = public)
        function setup(app, ColocFilterExclInput)
            app.InputImgs = ColocFilterExclInput.chans;
            app.ChanNames = fieldnames(app.InputImgs);
            dropdownItems = [{'<selection>'}, app.ChanNames'];

            app.ExcludeADropDown.Items = dropdownItems;
            app.ExcludeADropDown.Value = '<selection>';
            app.IfBDropDownExcl.Items = dropdownItems;
            app.IfBDropDownExcl.Value = '<selection>';

            % Update with example images
            exclimg = imread('ExcludeEg.png');
            imshow(exclimg, 'Parent', app.ExclusionImg);
            axis(app.ExclusionImg, 'image');

            exclvoximg = imread('ExcludeVoxEg.png');
            imshow(exclvoximg, 'Parent', app.ExclusionVoxImg);
            axis(app.ExclusionVoxImg, 'image');
        end

        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
     %       app.ColocExcludeBatch = struct();
      %      assignin('base', 'BatchColocExclude', app.ColocExcludeBatch);
            app.Output = true;
        end

        % Button pushed function: ExclusionAddButton
        function ExclusionAddButtonPushed(app, event)
            if ~isvarname(app.ExclusionName.Value)
                uialert(app.UIFigure, ...
                    'Filtered structure names must be unique and must start with a letter and contain only letters, digits, and underscores (no spaces or special characters).', ...
                    'Invalid Structure Name');
                return;
            end
            % Save selected channels with filtered structure name
            ExclChanName = app.ExcludeADropDown.Value;
            if strcmp(ExclChanName, '<selection>')
               uialert(app.UIFigure, ...
                    'You must select a channel from the "Exclude" dropdown menu.', ...
                    'No channel selected');
                return;
            end
            IfChanNameExcl = app.IfBDropDownExcl.Value;
            if strcmp(IfChanNameExcl, '<selection>')
               uialert(app.UIFigure, ...
                    'You must select a channel from the "Include" dropdown menu.', ...
                    'No channel selected');
                return;
            end

            % Collect images in struct
            ExclChanImg = app.InputImgs.(ExclChanName);
            IfChanImgExcl = app.InputImgs.(IfChanNameExcl);
            fieldNameExcl = app.ExclusionName.Value;
            ExclMode = app.FilterMode.SelectedObject.Tag;
            app.ColocExclude.(fieldNameExcl).ExcludeChan = ExclChanImg;
            app.ColocExclude.(fieldNameExcl).IfChanExcl = IfChanImgExcl;
            app.ColocExclude.(fieldNameExcl).ExclMode = ExclMode;

            % Collect names in struct (for batch)
            app.ColocExcludeBatch.(fieldNameExcl).ExcludeChan = ExclChanName;
            app.ColocExcludeBatch.(fieldNameExcl).IfChanExcl = IfChanNameExcl;
            app.ColocExcludeBatch.(fieldNameExcl).ExclMode = ExclMode;

            % Pass created structure name to list box
            newExcl = app.ExclusionName.Value;
            ExclMode = app.FilterMode.SelectedObject.Tag;
            newItem = sprintf('%s — %s', newExcl, ExclMode);
            
            currentItems = app.ExcludeStructuresListBox.Items;
            currentItems = currentItems(:);
            app.ExcludeStructuresListBox.Items = [currentItems; {newItem}];

            % Reset input fields
            app.ExclusionName.Value = '';
            app.ExcludeADropDown.Value = '<selection>';
            app.IfBDropDownExcl.Value = '<selection>';
        end

        % Button pushed function: DoneExitButton
        function DoneExitButtonPushed(app, event)

            assignin('base', 'ColocExclude', app.ColocExclude);
            assignin('base', 'BatchColocExclude', app.ColocExcludeBatch);
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoneExitButton = uibutton(app.UIFigure, 'push');
            app.DoneExitButton.ButtonPushedFcn = createCallbackFcn(app, @DoneExitButtonPushed, true);
            app.DoneExitButton.Text = 'Done';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip this step';
            app.FilterColoc = uipanel(app.UIFigure);
            app.FilterColoc.Title = 'Exclusion Channel Filter';
            app.FilterInstructions = uilabel(app.FilterColoc);
            app.FilterInstructions.Text = 'In the following steps, you will be given the opportunity to colocalize other channels with your glia structure(s). Before we get there, you may first want to filter signal from one channel (A) based on presence of overlap with another channel (B). For instance, suppose you have collected autofluorescence in its own channel (call it AF) and you are only interested in keeping signal from Channel A (green circles) if it does NOT overlap with the autofluorescence channel (red triangles). In this case, you would select your Channel A from the "Exclude" dropdown menu, and select AF from the "If" dropdown menu. Your original channels will be retained in addition to any additional filtered channels you create. If you don''t want to exclude signal based on overlapping signal from other channels, you can safely skip this step.';
            app.ExclusionFilter = uipanel(app.UIFigure);
            app.ExclusionFilter.Title = 'Filter Channel Structures';
            app.ExclusionImg = uiaxes(app.ExclusionFilter);
            app.ExclusionImg.XTick = [];
            app.ExclusionImg.YTick = [];
            app.ExclusionImg.ZTick = [];
            app.ExclusionVoxImg = uiaxes(app.ExclusionFilter);
            app.ExclusionVoxImg.XTick = [];
            app.ExclusionVoxImg.YTick = [];
            app.ExclusionVoxImg.ZTick = [];
            app.ExcludeADropDownLabel = uilabel(app.ExclusionFilter);
            app.ExcludeADropDownLabel.Text = 'Exclude (A):';
            app.ExcludeADropDown = uidropdown(app.ExclusionFilter);
            app.IfBDropDown_2Label = uilabel(app.ExclusionFilter);
            app.IfBDropDown_2Label.Text = 'If (B):';
            app.IfBDropDownExcl = uidropdown(app.ExclusionFilter);
            app.FilteredStructureNameexChannelA_noAFLabel = uilabel(app.ExclusionFilter);
            app.FilteredStructureNameexChannelA_noAFLabel.Text = {'Filtered Structure Name '; '(ex. ChannelA_noAF)'};
            app.ExclusionName = uieditfield(app.ExclusionFilter, 'text');
            app.ExclusionAddButton = uibutton(app.ExclusionFilter, 'push');
            app.ExclusionAddButton.ButtonPushedFcn = createCallbackFcn(app, @ExclusionAddButtonPushed, true);
            app.ExclusionAddButton.Text = 'Add';
            app.FilteredChannelsLabel = uilabel(app.ExclusionFilter);
            app.FilteredChannelsLabel.Text = 'Filtered Channels';
            app.ExcludeStructuresListBox = uilistbox(app.ExclusionFilter);
            app.ExcludeStructuresListBox.Items = {};
            app.ExcludeStructuresListBox.Value = {};
            app.FilterMode = uibuttongroup(app.ExclusionFilter);
            app.FilterMode.Title = 'Decide how to filter';
            app.Voxels = uitogglebutton(app.FilterMode);
            app.Voxels.Tag = 'Voxels';
            app.Voxels.Text = 'Remove Overlapping Voxels (upper ex)';
            app.Voxels.Value = true;
            app.Objects = uitogglebutton(app.FilterMode);
            app.Objects.Tag = 'Objects';
            app.Objects.Text = 'Remove Whole Objects (lower ex)';
            app.Both = uitogglebutton(app.FilterMode);
            app.Both.Tag = 'Both';
            app.Both.Text = 'Both (produces two filtered images)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocFilterExclusive_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end