classdef ColocFilterInclusive_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InclusionFilter           matlab.ui.container.Panel
        IncludeStructuresListBox  matlab.ui.control.ListBox
        FilteredChannelsLabel     matlab.ui.control.Label
        InclusionAddButton        matlab.ui.control.Button
        InclusionName             matlab.ui.control.EditField
        FilteredStructureNameexPV_virusPosEditFieldLabel  matlab.ui.control.Label
        KeepADropDown             matlab.ui.control.DropDown
        IfBDropDownIncl           matlab.ui.control.DropDown
        IfBDropDownLabel          matlab.ui.control.Label
        FilterLabel               matlab.ui.control.Label
        InclusionImg              matlab.ui.control.UIAxes
        FilterColoc               matlab.ui.container.Panel
        FilterInstructions        matlab.ui.control.Label
        SkipButton                matlab.ui.control.Button
        DoneExitButton            matlab.ui.control.Button
    end

    properties (Access = public)
        Output
        ChanNames
        InputImgs
        ColocInclude struct = struct()
        ColocIncludeBatch struct = struct()

    end

    methods (Access = public)
        function setup(app, ColocFilterInclInput)
            app.InputImgs = ColocFilterInclInput.chans;
            app.ChanNames = fieldnames(app.InputImgs);
            dropdownItems = [{'<selection>'}, app.ChanNames'];
            
            app.KeepADropDown.Items = dropdownItems;
            app.KeepADropDown.Value = '<selection>';
            app.IfBDropDownIncl.Items = dropdownItems;
            app.IfBDropDownIncl.Value = '<selection>';

            % Update with example images
            inclimg = imread('IncludeEg.png');
            imshow(inclimg, 'Parent', app.InclusionImg);
            axis(app.InclusionImg, 'image');
        end

        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
      %      app.ColocIncludeBatch = struct();
       %     assignin('base', 'BatchColocInclude', app.ColocIncludeBatch);
            app.Output = true;
        end

        % Button pushed function: InclusionAddButton
        function InclusionAddButtonPushed(app, event)
            if ~isvarname(app.InclusionName.Value)
                uialert(app.UIFigure, ...
                    'Filtered structure names must be unique and start with a letter and contain only letters, digits, and underscores (no spaces or special characters).', ...
                    'Invalid Structure Name');
                return;
            end
            % Save selected channels with filtered structure name
            KeepChanName = app.KeepADropDown.Value;
            if strcmp(KeepChanName, '<selection>')
               uialert(app.UIFigure, ...
                    'You must select a channel from the "Keep" dropdown menu.', ...
                    'No channel selected');
                return;
            end
            IfChanName = app.IfBDropDownIncl.Value;
            if strcmp(IfChanName, '<selection>')
               uialert(app.UIFigure, ...
                    'You must select a channel from the "If" dropdown menu.', ...
                    'No channel selected');
                return;
            end

            % Collect images in struct
            KeepChanImg = app.InputImgs.(KeepChanName);
            IfChanImg = app.InputImgs.(IfChanName);
            fieldName = app.InclusionName.Value;
            app.ColocInclude.(fieldName).KeepChan = KeepChanImg;
            app.ColocInclude.(fieldName).IfChan = IfChanImg;

            % Collect names in struct (for batch)
            app.ColocIncludeBatch.(fieldName).KeepChan = KeepChanName;
            app.ColocIncludeBatch.(fieldName).IfChan = IfChanName;
            
            % Pass created structure name to list box
            newFilter = app.InclusionName.Value;
            currentFilter = app.IncludeStructuresListBox.Items;
            currentFilter = currentFilter(:);
            newItem = {newFilter};
            app.IncludeStructuresListBox.Items = [currentFilter; newItem];

            % Reset input fields
            app.InclusionName.Value = '';
            app.KeepADropDown.Value = '<selection>';
            app.IfBDropDownIncl.Value = '<selection>';
        end

        % Button pushed function: DoneExitButton
        function DoneExitButtonPushed(app, event)
            assignin('base', 'ColocInclude', app.ColocInclude);
            assignin('base', 'BatchColocInclude', app.ColocIncludeBatch);
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoneExitButton = uibutton(app.UIFigure, 'push');
            app.DoneExitButton.ButtonPushedFcn = createCallbackFcn(app, @DoneExitButtonPushed, true);
            app.DoneExitButton.Text = 'Done';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip this step';
            app.FilterColoc = uipanel(app.UIFigure);
            app.FilterColoc.Title = 'Inclusion Channel Filter';
            app.FilterInstructions = uilabel(app.FilterColoc);
            app.FilterInstructions.Text = 'Similar to the previous step, you may want to keep identified objects/structures from one channel (A) only if it overlaps with signal from another channel (B). For instance, suppose you''re using an antibody to mark PV interneurons (green circles) and a virus with red fluorescent reporter (red triangles). If you are only interested in keeping PV interneurons that express the virus, you would select your PV marker channel from the "Keep" dropdown menu, and select your virus channel from the "If" dropdown menu. As before, your original channels will be retained in addition to any filtered channels you create. ';
            app.InclusionFilter = uipanel(app.UIFigure);
            app.InclusionFilter.Title = 'Filter Channel Structures';
            app.InclusionImg = uiaxes(app.InclusionFilter);
            app.InclusionImg.XTick = [];
            app.InclusionImg.YTick = [];
            app.InclusionImg.ZTick = [];
            app.FilterLabel = uilabel(app.InclusionFilter);
            app.FilterLabel.Text = 'Keep (A):';
            app.IfBDropDownLabel = uilabel(app.InclusionFilter);
            app.IfBDropDownLabel.Text = 'If (B):';
            app.IfBDropDownIncl = uidropdown(app.InclusionFilter);
            app.KeepADropDown = uidropdown(app.InclusionFilter);
            app.FilteredStructureNameexPV_virusPosEditFieldLabel = uilabel(app.InclusionFilter);
            app.FilteredStructureNameexPV_virusPosEditFieldLabel.Text = {'Filtered Structure Name '; '(ex. PV_virusPos)'};
            app.InclusionName = uieditfield(app.InclusionFilter, 'text');
            app.InclusionAddButton = uibutton(app.InclusionFilter, 'push');
            app.InclusionAddButton.ButtonPushedFcn = createCallbackFcn(app, @InclusionAddButtonPushed, true);
            app.InclusionAddButton.Text = 'Add';
            app.FilteredChannelsLabel = uilabel(app.InclusionFilter);
            app.FilteredChannelsLabel.Text = 'Filtered Channels';
            app.IncludeStructuresListBox = uilistbox(app.InclusionFilter);
            app.IncludeStructuresListBox.Items = {};
            app.IncludeStructuresListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocFilterInclusive_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end