classdef ColocGliaSelect_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        DoneButton       matlab.ui.control.Button
        SelectChannelstoColocalizeWithGliaStructuresPanel  matlab.ui.container.Panel
        ColocModeToggle  matlab.ui.container.ButtonGroup
        TextArea2_2      matlab.ui.control.TextArea
        Yes              matlab.ui.control.ToggleButton
        No               matlab.ui.control.ToggleButton
        SelectallchannelsthatyouwanttocolocalizewithyourgliastructuresLabel  matlab.ui.control.Label
        OrigTree         matlab.ui.container.CheckBoxTree
    end

    
    properties (Access = public)
        Output
        OrigChanNames
        InputImgs
        ColocMode
        ColocImgs struct = struct()
        BatchColocImgs struct = struct()
    end
    
    methods (Access = public)
        
        function setup(app, ColocSelectInput)
            app.InputImgs = ColocSelectInput.chans;
            app.OrigChanNames = fieldnames(app.InputImgs);

            OrigFields = app.OrigChanNames;
            for i = 1:numel(OrigFields)
                uitreenode(app.OrigTree, 'Text', OrigFields{i});
                expand(app.OrigTree);
            end
        end
        
        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            origNodes = app.OrigTree.CheckedNodes;
        
            for k = 1:numel(origNodes)
                node = origNodes(k);
                if isempty(node.Children)
                    chanName = node.Text;
                    app.ColocImgs.(chanName) = app.InputImgs.(chanName);
                    app.BatchColocImgs.(chanName) = chanName;
                end
            end

            app.ColocMode = str2double(app.ColocModeToggle.SelectedObject.Tag);
            Q = evalin('base', 'Q');
            Q.ColocAllAns = app.ColocMode;
            assignin('base', 'Q', Q);

            assignin('base', 'ColocObjectsSelect', app.ColocImgs);
            assignin('base', 'BatchColocObjectsSelect', app.BatchColocImgs);
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectChannelstoColocalizeWithGliaStructuresPanel = uipanel(app.UIFigure);
            app.SelectChannelstoColocalizeWithGliaStructuresPanel.Title = 'Select Channels to Colocalize With Glia Structures';
            app.OrigTree = uitree(app.SelectChannelstoColocalizeWithGliaStructuresPanel, 'checkbox');
            app.SelectallchannelsthatyouwanttocolocalizewithyourgliastructuresLabel = uilabel(app.SelectChannelstoColocalizeWithGliaStructuresPanel);
            app.SelectallchannelsthatyouwanttocolocalizewithyourgliastructuresLabel.Text = 'Select all channels that you want to colocalize with your glia structure(s)';
            app.ColocModeToggle = uibuttongroup(app.SelectChannelstoColocalizeWithGliaStructuresPanel);
            app.ColocModeToggle.Title = 'Colocalization Mode';
            app.No = uitogglebutton(app.ColocModeToggle);
            app.No.Tag = '0';
            app.No.Text = 'No, just single channel colocalization';
            app.No.Value = true;
            app.Yes = uitogglebutton(app.ColocModeToggle);
            app.Yes.Tag = '1';
            app.Yes.Text = 'Yes, all combinations';
            app.TextArea2_2 = uitextarea(app.ColocModeToggle);
            app.TextArea2_2.Value = {'Would you like to colocalize your glial structures with ALL COMBINATIONS of selected channels on the left, or only single colocalization with each channel separately?'};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocGliaSelect_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end