classdef ColocInfo_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ChannelsPanel  matlab.ui.container.Panel
        Ch1Name        matlab.ui.control.EditField
        Ch2Name        matlab.ui.control.EditField
        Ch3Name        matlab.ui.control.EditField
        Ch4Name        matlab.ui.control.EditField
        Ch5Name        matlab.ui.control.EditField
        Instructions   matlab.ui.control.Label
        Ch1CheckBox    matlab.ui.control.CheckBox
        Ch2CheckBox    matlab.ui.control.CheckBox
        Ch3CheckBox    matlab.ui.control.CheckBox
        Ch4CheckBox    matlab.ui.control.CheckBox
        Ch5CheckBox    matlab.ui.control.CheckBox
        DoneButton     matlab.ui.control.Button
        ColocalizationChannelSelectionPanel  matlab.ui.container.Panel
        SelectanychannelsyouwanttocolocalizewithyourglialstructureYoucanalsoaddcustomnamesforthechannelsifdesiredexDAPIIfyouleavethemblankthenameswilldefaulttochannelorderLabel  matlab.ui.control.Label
    end

    % Properties added by user
    properties (Access = public)
     %   ColocCh1 = false
        Output
    end
    
    % Helper functions added by user
    methods (Access = private)
        
        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
            app.Ch1Name.Enable = 'off';
            app.Ch2Name.Enable = 'off';
            app.Ch3Name.Enable = 'off';
            app.Ch4Name.Enable = 'off';
            app.Ch5Name.Enable = 'off';
        end

        % Value changed function: Ch1CheckBox
        function Ch1CheckBoxValueChanged(app, event)
         %   app.ColocCh1 = app.Ch1CheckBox.Value;
            isChecked = app.Ch1CheckBox.Value;
            if isChecked
                app.Ch1Name.Enable = 'on';
            else
                app.Ch1Name.Enable = 'off';
            end
        end

        % Value changed function: Ch2CheckBox
        function Ch2CheckBoxValueChanged(app, event)
            isChecked = app.Ch2CheckBox.Value;
            if isChecked
                app.Ch2Name.Enable = 'on';
            else
                app.Ch2Name.Enable = 'off';
            end
        end

        % Value changed function: Ch3CheckBox
        function Ch3CheckBoxValueChanged(app, event)
            isChecked = app.Ch3CheckBox.Value;
            if isChecked
                app.Ch3Name.Enable = 'on';
            else
                app.Ch3Name.Enable = 'off';
            end
        end

        % Value changed function: Ch4CheckBox
        function Ch4CheckBoxValueChanged(app, event)
            isChecked = app.Ch4CheckBox.Value;
            if isChecked
                app.Ch4Name.Enable = 'on';
            else
                app.Ch4Name.Enable = 'off';
            end
        end

        % Value changed function: Ch5CheckBox
        function Ch5CheckBoxValueChanged(app, event)
            isChecked = app.Ch5CheckBox.Value;
            if isChecked
                app.Ch5Name.Enable = 'on';
            else
                app.Ch5Name.Enable = 'off';
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            ColocChannelNames = {};
            ColocChannels = [];

            for c = 1:5
                checkboxName = sprintf('Ch%dCheckBox', c);
                nameProp = sprintf('Ch%dName', c);
        
                if isprop(app, checkboxName) && app.(checkboxName).Value
                    ColocChannels(end+1) = c;
                    if isprop(app, nameProp)
                        nameVal = strtrim(app.(nameProp).Value);
                        if isempty(nameVal)
                            nameVal = sprintf('Channel%d', c);
                        end
                    else
                        nameVal = sprintf('Channel%d', c);
                    end
                    ColocChannelNames{end+1} = nameVal;
                end
            end
            assignin('base', 'ColocChannels', ColocChannels);
            assignin('base', 'ColocChannelNames', ColocChannelNames);

            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColocalizationChannelSelectionPanel = uipanel(app.UIFigure);
            app.ColocalizationChannelSelectionPanel.Title = 'Colocalization Channel Selection';
            app.SelectanychannelsyouwanttocolocalizewithyourglialstructureYoucanalsoaddcustomnamesforthechannelsifdesiredexDAPIIfyouleavethemblankthenameswilldefaulttochannelorderLabel = uilabel(app.ColocalizationChannelSelectionPanel);
            app.SelectanychannelsyouwanttocolocalizewithyourglialstructureYoucanalsoaddcustomnamesforthechannelsifdesiredexDAPIIfyouleavethemblankthenameswilldefaulttochannelorderLabel.Text = 'Select any channel(s) you want to colocalize with your glial structure. You can also add custom names for the channels if desired (ex. "DAPI"). If you leave them blank the names will default to channel order.';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.Ch5CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch5CheckBoxValueChanged, true);
            app.Ch5CheckBox.Text = 'Ch5';
            app.Ch4CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch4CheckBoxValueChanged, true);
            app.Ch4CheckBox.Text = 'Ch4';
            app.Ch3CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch3CheckBoxValueChanged, true);
            app.Ch3CheckBox.Text = 'Ch3';
            app.Ch2CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch2CheckBoxValueChanged, true);
            app.Ch2CheckBox.Text = 'Ch2';
            app.Ch1CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch1CheckBoxValueChanged, true);
            app.Ch1CheckBox.Text = 'Ch1';
            app.Instructions = uilabel(app.ChannelsPanel);
            app.Instructions.Text = 'Channel Name';
            app.Ch5Name = uieditfield(app.ChannelsPanel, 'text');
            app.Ch4Name = uieditfield(app.ChannelsPanel, 'text');
            app.Ch3Name = uieditfield(app.ChannelsPanel, 'text');
            app.Ch2Name = uieditfield(app.ChannelsPanel, 'text');
            app.Ch1Name = uieditfield(app.ChannelsPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocInfo_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end