classdef ColocInstructions_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TextArea_4                 matlab.ui.control.TextArea
        Step3CombinedChannelStructuresPanel  matlab.ui.container.Panel
        andorLabel_2               matlab.ui.control.Label
        TextArea_3                 matlab.ui.control.TextArea
        OverlapOnlyImg             matlab.ui.control.UIAxes
        FuseAllImg                 matlab.ui.control.UIAxes
        Step2InclusionFilterPanel  matlab.ui.container.Panel
        TextArea_2                 matlab.ui.control.TextArea
        InclusionImg               matlab.ui.control.UIAxes
        Step1ExlusionFilterPanel   matlab.ui.container.Panel
        andorLabel                 matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        ExclusionVoxImg            matlab.ui.control.UIAxes
        ExclusionImg               matlab.ui.control.UIAxes
        ContinueButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        Output
    end
    
    methods (Access = public)
        
        function setup(app)
            exclimg = imread('ExcludeEg.png');
            imshow(exclimg, 'Parent', app.ExclusionImg);
            axis(app.ExclusionImg, 'image');

            exclvoximg = imread('ExcludeVoxEg.png');
            imshow(exclvoximg, 'Parent', app.ExclusionVoxImg);
            axis(app.ExclusionVoxImg, 'image');
                        
            inclimg = imread('IncludeEg.png');
            imshow(inclimg, 'Parent', app.InclusionImg);
            axis(app.InclusionImg, 'image');
                        
            Fuseimg = imread('FuseAllEg.png');
            imshow(Fuseimg, 'Parent', app.FuseAllImg);
            axis(app.FuseAllImg, 'image');
            
            Overlapimg = imread('OverlapOnlyEg.png');
            imshow(Overlapimg, 'Parent', app.OverlapOnlyImg);
            axis(app.OverlapOnlyImg, 'image');
        end
                
        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.Step1ExlusionFilterPanel = uipanel(app.UIFigure);
            app.Step1ExlusionFilterPanel.Title = 'Step 1: Exlusion Filter';
            app.ExclusionImg = uiaxes(app.Step1ExlusionFilterPanel);
            app.ExclusionImg.XTick = [];
            app.ExclusionImg.YTick = [];
            app.ExclusionImg.ZTick = [];
            app.ExclusionVoxImg = uiaxes(app.Step1ExlusionFilterPanel);
            app.ExclusionVoxImg.XTick = [];
            app.ExclusionVoxImg.YTick = [];
            app.ExclusionVoxImg.ZTick = [];
            app.TextArea = uitextarea(app.Step1ExlusionFilterPanel);
            app.TextArea.Value = {'This step allows you to filter signal from one channel (A) based on presence of overlap with another channel (B). For instance, suppose you have collected autofluorescence in its own channel (B) and you are only interested in keeping signal from Channel A (green circles) if it does NOT overlap with the autofluorescence channel (red triangles). You can choose to remove only overlapping voxels (left example), remove entire objects (right example), or both (each will be saved as separate images).'};
            app.andorLabel = uilabel(app.Step1ExlusionFilterPanel);
            app.andorLabel.Text = 'and/ or';
            app.Step2InclusionFilterPanel = uipanel(app.UIFigure);
            app.Step2InclusionFilterPanel.Title = 'Step 2: Inclusion Filter';
            app.InclusionImg = uiaxes(app.Step2InclusionFilterPanel);
            app.InclusionImg.XTick = [];
            app.InclusionImg.YTick = [];
            app.InclusionImg.ZTick = [];
            app.TextArea_2 = uitextarea(app.Step2InclusionFilterPanel);
            app.TextArea_2.Value = {'Similar to the previous step, you may want to keep identified objects/structures from one channel (A) only if it overlaps with signal from another channel (B). For instance, suppose you''re using an antibody to mark PV interneurons (green circles) and an AAV with red fluorescent reporter (red triangles). In this step, you can choose to keep only PV interneurons (green) that express your AAV (red).'};
            app.Step3CombinedChannelStructuresPanel = uipanel(app.UIFigure);
            app.Step3CombinedChannelStructuresPanel.Title = 'Step 3: Combined Channel Structures';
            app.FuseAllImg = uiaxes(app.Step3CombinedChannelStructuresPanel);
            app.FuseAllImg.XTick = [];
            app.FuseAllImg.YTick = [];
            app.FuseAllImg.ZTick = [];
            app.OverlapOnlyImg = uiaxes(app.Step3CombinedChannelStructuresPanel);
            app.OverlapOnlyImg.XTick = [];
            app.OverlapOnlyImg.YTick = [];
            app.OverlapOnlyImg.ZTick = [];
            app.TextArea_3 = uitextarea(app.Step3CombinedChannelStructuresPanel);
            app.TextArea_3.Value = {'You might be using 2 or more markers for the same structure (e.g., PSD95 and Shank3 for excitatory synapses) and that you want to combine in specific ways. You have the option to combine selected structures into a composite (left example), or keep only those voxels that overlap between them (right example), or you can do both (each will be saved as separate images). '};
            app.andorLabel_2 = uilabel(app.Step3CombinedChannelStructuresPanel);
            app.andorLabel_2.Text = 'and/ or';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'Below is an explanation of the next few steps in the script that pertain to colocalization. Each subsequent step allows you to use products of the preceeding step, and each step can be skipped. The instructions and graphical examples will be reiterated in each step as you get to them.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocInstructions_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end