classdef ColocProximity_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        mLabel                      matlab.ui.control.Label
        SelectChannelsListBox       matlab.ui.control.ListBox
        SelectChannelsListBoxLabel  matlab.ui.control.Label
        NewStructureNameexexcitatorysynapsesLabel  matlab.ui.control.Label
        ProxCutoffInput             matlab.ui.control.NumericEditField
        ProximityCutoffListBox      matlab.ui.control.ListBox
        ProximityDistanceCutoffsListBoxLabel  matlab.ui.control.Label
        SkipButton                  matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        DoneExitButton              matlab.ui.control.Button
        MultiColoc                  matlab.ui.container.Panel
        Instructions                matlab.ui.control.Label
    end

    properties (Access = public)
        Output
        ChanNames
        InputImgs
        ProxSkip
        ColocProxCC struct = struct()
        BatchColocProxCC struct = struct()
    end

    methods (Access = public)
        function setup(app, ColocProxInput)
            app.InputImgs = ColocProxInput.chans;
            app.ChanNames = fieldnames(app.InputImgs);
            app.SelectChannelsListBox.Items = app.ChanNames;
        end

        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            
            app.ProxSkip = 1;
            assignin('base', 'ProxSkip', app.ProxSkip);
            app.BatchColocProxCC = [];
            assignin('base', 'BatchProxCC', app.BatchColocProxCC)
            app.Output = true;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isempty(app.ProxCutoffInput.Value) || isnan(app.ProxCutoffInput.Value) || app.ProxCutoffInput.Value <= 0
                uialert(app.UIFigure, ...
                    'Please enter a positive number.', ...
                    'Invalid Number');
                return;
            end

            if isempty(app.SelectChannelsListBox.Value)
                uialert(app.UIFigure, ...
                    'Please select at least one channel from the list on the left.', ...
                    'No Channels Selected');
                return;
            end

            % Get numeric value from input and selected channels
            numericValue = app.ProxCutoffInput.Value;
            selectedChannels = app.SelectChannelsListBox.Value;
            
            % Add each selected channel with its image and cutoffs
            for i = 1:numel(selectedChannels)
                chanName = selectedChannels{i};
                app.ColocProxCC.(chanName).Image = app.InputImgs.(chanName);
                if isfield(app.ColocProxCC, chanName) && isfield(app.ColocProxCC.(chanName), 'Cutoff')
                    app.ColocProxCC.(chanName).Cutoff(end+1) = numericValue;
                else
                    app.ColocProxCC.(chanName).Image = app.InputImgs.(chanName);
                    app.ColocProxCC.(chanName).Cutoff = numericValue;
                end

                if isfield(app.BatchColocProxCC, chanName) && isfield(app.BatchColocProxCC.(chanName), 'Cutoff')
                    app.BatchColocProxCC.(chanName).Cutoff(end+1) = numericValue;
                else
                    app.BatchColocProxCC.(chanName).Cutoff = numericValue;
                end
            end

            % Pass proximity cutoff value and channel name(s) to list box
            newItems = cellfun(@(c) sprintf('%s — %.2f µm', c, numericValue), ...
                selectedChannels, 'UniformOutput', false);
            currentItems = app.ProximityCutoffListBox.Items(:);
            app.ProximityCutoffListBox.Items = [currentItems; newItems(:)];
            
            % Reset input fields
            app.ProxCutoffInput.Value = [];
            app.SelectChannelsListBox.Value = {};
        end

        % Button pushed function: DoneExitButton
        function DoneExitButtonPushed(app, event)
            if isempty(fieldnames(app.ColocProxCC))
                uialert(app.UIFigure, ...
                    ['You have not added any proximity channels with cutoffs. ' ...
                    'Choose at least one channel and input one numeric cutoff, then press the Add button. ' ...
                    'If you do not wish to do proximity analysis, simply press the Skip This Step button. '], ...
                    'Nothing Selected');
                return;
            end

            assignin('base', 'ProxCC', app.ColocProxCC);
            assignin('base', 'BatchProxCC', app.BatchColocProxCC);
            app.ProxSkip = 0;
            assignin('base', 'ProxSkip', app.ProxSkip);

            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MultiColoc = uipanel(app.UIFigure);
            app.MultiColoc.Title = 'Proximity Detection';
            app.Instructions = uilabel(app.MultiColoc);
            app.Instructions.Text = 'This step will allow you to detect objects from other channels that are within a specified proximity of your glia (which includes those structures that overlap with your glia structure). This can be a computationally intensive and time consuming process, so if you are not interesed in this data you can skip this step.';
            app.DoneExitButton = uibutton(app.UIFigure, 'push');
            app.DoneExitButton.ButtonPushedFcn = createCallbackFcn(app, @DoneExitButtonPushed, true);
            app.DoneExitButton.Text = 'Done';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip this step';
            app.ProximityDistanceCutoffsListBoxLabel = uilabel(app.UIFigure);
            app.ProximityDistanceCutoffsListBoxLabel.Text = 'Proximity Distance Cutoffs';
            app.ProximityCutoffListBox = uilistbox(app.UIFigure);
            app.ProximityCutoffListBox.Items = {''};
            app.ProximityCutoffListBox.Value = {};
            app.ProxCutoffInput = uieditfield(app.UIFigure, 'numeric');
            app.ProxCutoffInput.Limits = [0 Inf];
            app.ProxCutoffInput.AllowEmpty = 'on';
            app.ProxCutoffInput.Value = [];
            app.NewStructureNameexexcitatorysynapsesLabel = uilabel(app.UIFigure);
            app.NewStructureNameexexcitatorysynapsesLabel.Text = 'Input Proximity Distance Cutoff';
            app.SelectChannelsListBoxLabel = uilabel(app.UIFigure);
            app.SelectChannelsListBoxLabel.Text = 'Select Channels';
            app.SelectChannelsListBox = uilistbox(app.UIFigure);
            app.SelectChannelsListBox.Items = {};
            app.SelectChannelsListBox.Multiselect = 'on';
            app.SelectChannelsListBox.Value = {};
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'μm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocProximity_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end