classdef ColocSaveImages_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea2_2               matlab.ui.control.TextArea
        DoneButton                matlab.ui.control.Button
        SaveFilteredandOverlaidImagesPanel  matlab.ui.container.Panel
        SaveModeOverlay           matlab.ui.container.ButtonGroup
        BothButton_3              matlab.ui.control.ToggleButton
        DButton_4                 matlab.ui.control.ToggleButton
        DButton_3                 matlab.ui.control.ToggleButton
        SaveModeOrig              matlab.ui.container.ButtonGroup
        BothButton_2              matlab.ui.control.ToggleButton
        DButton_2                 matlab.ui.control.ToggleButton
        DButton                   matlab.ui.control.ToggleButton
        SkipButton                matlab.ui.control.Button
        SaveImagesofOverlapwithGliaLabel  matlab.ui.control.Label
        SaveImagesofChannelLabel  matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        OrigTree                  matlab.ui.container.CheckBoxTree
        OverlapTree               matlab.ui.container.CheckBoxTree
    end

    
    properties (Access = public)
        Output
        OrigChanNames
        OverlapChanNames
        InputOrigImgs
        InputOverlapImgs
        ColocSaveModeOrig = 0
        ColocSaveModeOverlay = 0
        ColocOrigImgs struct = struct()
        ColocOverlapImgs struct = struct()
        BatchColocOrigImgs struct = struct()
        BatchColocOverlapImgs struct = struct()
    end
    
    methods (Access = public)
        
        function setup(app, ColocSaveInput)
            app.InputOrigImgs = ColocSaveInput.OrigChans;
            app.InputOverlapImgs = ColocSaveInput.OverlapChans;
            app.OrigChanNames = fieldnames(app.InputOrigImgs);
            app.OverlapChanNames = fieldnames(app.InputOverlapImgs);

            OrigFields = app.OrigChanNames;
            for i = 1:numel(OrigFields)
                uitreenode(app.OrigTree, 'Text', OrigFields{i});
                expand(app.OrigTree);
            end
              
            OverlapFields = app.OverlapChanNames;
            for i = 1:numel(OverlapFields)
                uitreenode(app.OverlapTree, 'Text', OverlapFields{i});
                expand(app.OverlapTree);
            end
        end
        
        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            centerAppOnScreen(app);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            origNodes = app.OrigTree.CheckedNodes;
        
            for k = 1:numel(origNodes)
                node = origNodes(k);
                if isempty(node.Children)
                    chanName = node.Text;
                    app.ColocOrigImgs.(chanName) = app.InputOrigImgs.(chanName);
                    app.BatchColocOrigImgs.(chanName) = chanName;
                end
            end

            overlapNodes = app.OverlapTree.CheckedNodes;
        
            for k = 1:numel(overlapNodes)
                node = overlapNodes(k);
                if isempty(node.Children)
                    chanName = node.Text;
                    app.ColocOverlapImgs.(chanName) = app.InputOverlapImgs.(chanName);
                    app.BatchColocOverlapImgs.(chanName) = chanName;
                end
            end

            app.ColocSaveModeOrig = str2double(app.SaveModeOrig.SelectedObject.Tag);
            app.ColocSaveModeOverlay = str2double(app.SaveModeOverlay.SelectedObject.Tag);

            assignin('base', 'ColocOrigImgs', app.ColocOrigImgs);
            assignin('base', 'ColocOverlapImgs', app.ColocOverlapImgs);
            assignin('base', 'BatchColocOrigImgs', app.BatchColocOrigImgs);
            assignin('base', 'BatchColocOverlapImgs', app.BatchColocOverlapImgs);
            assignin('base', 'ColocSaveModeOrig', app.ColocSaveModeOrig);
            assignin('base', 'ColocSaveModeOverlay', app.ColocSaveModeOverlay);

            app.Output = true;
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.ColocSaveModeOrig = 0;
            app.ColocSaveModeOverlay = 0;
            assignin('base', 'BatchColocOrigImgs', []);
            assignin('base', 'BatchColocOverlapImgs', []);
            assignin('base', 'ColocSaveModeOrig', app.ColocSaveModeOrig);
            assignin('base', 'ColocSaveModeOverlay', app.ColocSaveModeOverlay);
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveFilteredandOverlaidImagesPanel = uipanel(app.UIFigure);
            app.SaveFilteredandOverlaidImagesPanel.Title = 'Save Filtered and Overlaid Images';
            app.OverlapTree = uitree(app.SaveFilteredandOverlaidImagesPanel, 'checkbox');
            app.OrigTree = uitree(app.SaveFilteredandOverlaidImagesPanel, 'checkbox');
            app.TextArea = uitextarea(app.SaveFilteredandOverlaidImagesPanel);
            app.TextArea.Value = {'Select all channels for which you want to save a copy of the channel alone and/or overlaid with your glia structures image. '};
            app.SaveImagesofChannelLabel = uilabel(app.SaveFilteredandOverlaidImagesPanel);
            app.SaveImagesofChannelLabel.Text = 'Save Image(s) of Channel';
            app.SaveImagesofOverlapwithGliaLabel = uilabel(app.SaveFilteredandOverlaidImagesPanel);
            app.SaveImagesofOverlapwithGliaLabel.Text = 'Save Image(s) of Overlap with Glia';
            app.SkipButton = uibutton(app.SaveFilteredandOverlaidImagesPanel, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip Saving Images (quantitative data will still be produced)';
            app.SaveModeOrig = uibuttongroup(app.SaveFilteredandOverlaidImagesPanel);
            app.DButton = uitogglebutton(app.SaveModeOrig);
            app.DButton.Tag = '2';
            app.DButton.Text = '2D';
            app.DButton.Value = true;
            app.DButton_2 = uitogglebutton(app.SaveModeOrig);
            app.DButton_2.Tag = '3';
            app.DButton_2.Text = '3D';
            app.BothButton_2 = uitogglebutton(app.SaveModeOrig);
            app.BothButton_2.Tag = '1';
            app.BothButton_2.Text = 'Both';
            app.SaveModeOverlay = uibuttongroup(app.SaveFilteredandOverlaidImagesPanel);
            app.DButton_3 = uitogglebutton(app.SaveModeOverlay);
            app.DButton_3.Tag = '2';
            app.DButton_3.Text = '2D';
            app.DButton_3.Value = true;
            app.DButton_4 = uitogglebutton(app.SaveModeOverlay);
            app.DButton_4.Tag = '3';
            app.DButton_4.Text = '3D';
            app.BothButton_3 = uitogglebutton(app.SaveModeOverlay);
            app.BothButton_3.Tag = '1';
            app.BothButton_3.Text = 'Both';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.TextArea2_2 = uitextarea(app.UIFigure);
            app.TextArea2_2.Value = {'NOTE: 3D rendering is significantly more computationally demanding than 2D rendering. Depending on the number and size of your images, selecting 3D could result in much longer processing times.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocSaveImages_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end