classdef FileDataGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveDataLocationPanel        matlab.ui.container.Panel
        TextArea                     matlab.ui.control.TextArea
        SavePathLabel                matlab.ui.control.Label
        SaveLabel                    matlab.ui.control.Label
        SelectLocationButton         matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        FileSelectionPanel           matlab.ui.container.Panel
        ChannelOfInterest2           matlab.ui.control.NumericEditField
        GliaStructureChannelLabel_2  matlab.ui.control.Label
        YesCheckBox                  matlab.ui.control.CheckBox
        TextArea_3                   matlab.ui.control.TextArea
        ChannelOfInterest            matlab.ui.control.NumericEditField
        GliaStructureChannelLabel    matlab.ui.control.Label
        SelectedFileLabel            matlab.ui.control.Label
        ShowFileNameLabel            matlab.ui.control.Label
        FilemustbeintifformatLabel   matlab.ui.control.Label
        SelectFileButton             matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
    end

    % Properties added by user
    properties (Access = public)
        SelectedFileName % this is where the name of your selected file will be stored
        SelectedPathName % this is where the path of your selected file will be stored
        ChannelOfInterestValue % this is where the channel number will be stored
        ChannelOfInterest2Value % if you select second glia channel, this is where its number will be stored
        SavePath string = "" % this is where all data will be saved
        Output
    end
    
    % Helper functions added by user
    methods (Access = private)
        
        function centerAppOnScreen(app)
            % Get screen size
            screenSize = get(0, 'ScreenSize');

            % Get app window size
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
    
            % Calculate centered position
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;

            % Set new position
            app.UIFigure.Position(1:2) = [left, bottom];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
            app.ChannelOfInterest2.Enable = 'off'; % disable second glia channel number
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.tif'}); % only look for .tif files
            if isequal(fileName, 0)
                return;
            end
            app.SelectedFileName = fileName; % store selected file name in app properties
            app.SelectedPathName = pathName; % store selected file path in app properties
            app.ShowFileNameLabel.Text = fileName;
        end

        % Value changed function: ChannelOfInterest
        function ChannelOfInterestValueChanged(app, event)
            app.ChannelOfInterestValue = app.ChannelOfInterest.Value;
        end

        % Value changed function: YesCheckBox
        function YesCheckBoxValueChanged(app, event)
            isChecked = app.YesCheckBox.Value;
            if isChecked
                app.ChannelOfInterest2.Enable = 'on';
            else
                app.ChannelOfInterest2.Enable = 'off';
            end
            
        end

        % Value changed function: ChannelOfInterest2
        function ChannelOfInterest2ValueChanged(app, event)
            app.ChannelOfInterest2Value = app.ChannelOfInterest2.Value;
        end

        % Button pushed function: SelectLocationButton
        function SelectLocationButtonPushed(app, event)
            folder = uigetdir(pwd, 'Select Folder to Save Data');
            if folder ~= 0
                app.SavePath = folder;
                app.SaveLabel.Text = folder; 
            else
                uialert(app.UIFigure, 'No folder selected.', 'Selection Cancelled');
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if isempty(app.SelectedFileName) || strlength(strtrim(app.SelectedFileName)) == 0
                uialert(app.UIFigure, 'You must select a valid file to proceed.', ...
                        'Invalid File');
                return;
            end

            % check chan 1
            chan = app.ChannelOfInterestValue;
            if ~isscalar(chan) || ~isnumeric(chan) || chan ~= floor(chan) || chan < 1 || chan > 5 
                uialert(app.UIFigure, 'Your glia structure channel must be an integer no greater than 5.', ...
                        'Invalid Channel');
                return;
            end

            % check chan 2 if applicable
            if app.YesCheckBox.Value
                chan2 = app.ChannelOfInterest2Value;
                if ~isscalar(chan2) || ~isnumeric(chan2) || chan2 ~= floor(chan2) || chan2 < 1 || chan2 > 5 
                    uialert(app.UIFigure, 'Your second glia channel must be an integer no greater than 5.', ...
                            'Invalid Channel');
                    return;
                end
            else
                % create dummy variables for paramters file
                assignin('base', 'ThreshMode2', []);
                assignin('base', 'ThreshMethod2', []);
                assignin('base', 'ShowImg2', []);
                assignin('base', 'SaveOrig2', []);
                assignin('base', 'NoiseIm2', []);
                assignin('base', 'noise2', []);
                assignin('base', 'ThreshSet2', []);
                assignin('base', 'ThreshLevel2', []);
                assignin('base', 'FillGaps2', []);
                assignin('base', 'FillGapsRadius2', []);
                assignin('base', 'SaveOverlapImg', []);
                assignin('base', 'SecondGliaChanChoice', []);
            end

             
            if isempty(app.SavePath) || strlength(strtrim(app.SavePath)) == 0
                uialert(app.UIFigure, 'You must select a valid save path to proceed.', ...
                        'Invalid Save Path');
                return;
            end

            % save relevant app properties as variables to base workspace
            assignin('base', 'file', app.SelectedFileName);
            assignin('base', 'pathname', app.SelectedPathName);
            assignin('base', 'GliaChannel', app.ChannelOfInterestValue);
            assignin('base', 'SavePath', app.SavePath);
            assignin('base', 'SecondGliaChanYN', app.YesCheckBox.Value);
            assignin('base', 'GliaChannel2', app.ChannelOfInterest2Value);

            app.Output = true; % this allows the script to kill the app and continue
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileSelectionPanel = uipanel(app.UIFigure);
            app.FileSelectionPanel.Title = 'File Selection';
            app.TextArea_2 = uitextarea(app.FileSelectionPanel);
            app.TextArea_2.Value = {'Indicate the primary channel in which your glia structure may be found. This should be a number (1,2,3, etc.), not a name (Iba1, GFAP, etc.).'};
            app.SelectFileButton = uibutton(app.FileSelectionPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FilemustbeintifformatLabel = uilabel(app.FileSelectionPanel);
            app.FilemustbeintifformatLabel.Text = 'File must be in .tif format';
            app.ShowFileNameLabel = uilabel(app.FileSelectionPanel);
            app.ShowFileNameLabel.Text = '';
            app.SelectedFileLabel = uilabel(app.FileSelectionPanel);
            app.SelectedFileLabel.Text = 'Selected File:';
            app.GliaStructureChannelLabel = uilabel(app.FileSelectionPanel);
            app.GliaStructureChannelLabel.Text = 'Glia Structure Channel';
            app.ChannelOfInterest = uieditfield(app.FileSelectionPanel, 'numeric');
            app.ChannelOfInterest.ValueChangedFcn = createCallbackFcn(app, @ChannelOfInterestValueChanged, true);
            app.TextArea_3 = uitextarea(app.FileSelectionPanel);
            app.TextArea_3.Value = {'Do you have a second structural channel or a cell-type identity channel for your glia in addition to the primary channel indicated above (for example, Tmem119, or fluorescent reporter)?  If yes, check "Yes" and indicate the channel number like you did above for the primary channel. If not, you can ignore this box.'};
            app.YesCheckBox = uicheckbox(app.FileSelectionPanel);
            app.YesCheckBox.ValueChangedFcn = createCallbackFcn(app, @YesCheckBoxValueChanged, true);
            app.YesCheckBox.Text = 'Yes';
            app.GliaStructureChannelLabel_2 = uilabel(app.FileSelectionPanel);
            app.GliaStructureChannelLabel_2.Text = '2nd Channel number';
            app.ChannelOfInterest2 = uieditfield(app.FileSelectionPanel, 'numeric');
            app.ChannelOfInterest2.ValueChangedFcn = createCallbackFcn(app, @ChannelOfInterest2ValueChanged, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SaveDataLocationPanel = uipanel(app.UIFigure);
            app.SaveDataLocationPanel.Title = 'Save Data Location?';
            app.SelectLocationButton = uibutton(app.SaveDataLocationPanel, 'push');
            app.SelectLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLocationButtonPushed, true);
            app.SelectLocationButton.Text = 'Select Location';
            app.SaveLabel = uilabel(app.SaveDataLocationPanel);
            app.SaveLabel.Text = '';
            app.SavePathLabel = uilabel(app.SaveDataLocationPanel);
            app.SavePathLabel.Text = 'Save Path:';
            app.TextArea = uitextarea(app.SaveDataLocationPanel);
            app.TextArea.Value = {'Select the location in which you would like data to be saved. A new folder will be created with the name of the image file you selected above.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FileDataGUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end