classdef FileDataOverride_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Instructions                    matlab.ui.control.TextArea
        LabelpxXY                       matlab.ui.control.Label
        LabelpxZ                        matlab.ui.control.Label
        XYscalewidthheightofapixelLabel  matlab.ui.control.Label
        ZscaledistancebetweenslicesLabel  matlab.ui.control.Label
        ExistingMetadataLabel           matlab.ui.control.Label
        ManualOverrideLabel             matlab.ui.control.Label
        ManualXYscaleCheckBox           matlab.ui.control.CheckBox
        ManualZscaleCheckBox            matlab.ui.control.CheckBox
        ManualXY                        matlab.ui.control.EditField
        ManualZ                         matlab.ui.control.EditField
        umlab                           matlab.ui.control.Label
        umlab3                          matlab.ui.control.Label
        umlab4                          matlab.ui.control.Label
        umlab6                          matlab.ui.control.Label
        DoneButton                      matlab.ui.control.Button
        CheckFileMetadataTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        pxXY
        pxZ
        Output
    end
    
    methods (Access = public)
        
        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end

        function setup(app, InfoInput)
            app.pxXY = InfoInput.pxXY;
            app.pxZ = InfoInput.pxZ;
            
            function txt = formatVoxelDisplay(val)
                if isnumeric(val)
                    txt = sprintf('%.3f', val);
                elseif ischar(val) || isstring(val)
                    txt = char(val);
                else
                    txt = 'Invalid';
                end
            end

            app.LabelpxXY.Text = formatVoxelDisplay(app.pxXY);
            app.LabelpxZ.Text = formatVoxelDisplay(app.pxZ);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
            app.ManualXY.Enable = 'off';
            app.ManualZ.Enable = 'off';
        end

        % Value changed function: ManualXYscaleCheckBox
        function ManualXYscaleCheckBoxValueChanged(app, event)
            isChecked = app.ManualXYscaleCheckBox.Value;
            if isChecked
                app.ManualXY.Enable = 'on';
            else
                app.ManualXY.Enable = 'off';
            end
        end

        % Value changed function: ManualZscaleCheckBox
        function ManualZscaleCheckBoxValueChanged(app, event)
            isChecked = app.ManualZscaleCheckBox.Value;
            if isChecked
                app.ManualZ.Enable = 'on';
            else
                app.ManualZ.Enable = 'off';
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            isCheckedXY = app.ManualXYscaleCheckBox.Value;
            isCheckedZ = app.ManualZscaleCheckBox.Value;

            if isCheckedXY
                assignin('base', 'pxXY', str2double(app.ManualXY.Value));
            end
            
            if isCheckedZ
                assignin('base', 'pxZ', str2double(app.ManualZ.Value));
            end
            
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckFileMetadataTextAreaLabel = uilabel(app.UIFigure);
            app.CheckFileMetadataTextAreaLabel.Text = 'Check File Metadata';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.umlab6 = uilabel(app.UIFigure);
            app.umlab6.Text = 'μm';
            app.umlab4 = uilabel(app.UIFigure);
            app.umlab4.Text = 'μm';
            app.umlab3 = uilabel(app.UIFigure);
            app.umlab3.Text = 'μm';
            app.umlab = uilabel(app.UIFigure);
            app.umlab.Text = 'μm';
            app.ManualZ = uieditfield(app.UIFigure, 'text');
            app.ManualXY = uieditfield(app.UIFigure, 'text');
            app.ManualZscaleCheckBox = uicheckbox(app.UIFigure);
            app.ManualZscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualZscaleCheckBoxValueChanged, true);
            app.ManualZscaleCheckBox.Text = 'Manual Z scale';
            app.ManualXYscaleCheckBox = uicheckbox(app.UIFigure);
            app.ManualXYscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualXYscaleCheckBoxValueChanged, true);
            app.ManualXYscaleCheckBox.Text = 'Manual XY scale';
            app.ManualOverrideLabel = uilabel(app.UIFigure);
            app.ManualOverrideLabel.Text = 'Manual Override';
            app.ExistingMetadataLabel = uilabel(app.UIFigure);
            app.ExistingMetadataLabel.Text = 'Existing Metadata';
            app.ZscaledistancebetweenslicesLabel = uilabel(app.UIFigure);
            app.ZscaledistancebetweenslicesLabel.Text = 'Z scale (distance between slices) =';
            app.XYscalewidthheightofapixelLabel = uilabel(app.UIFigure);
            app.XYscalewidthheightofapixelLabel.Text = 'XY scale (width & height of a pixel) =';
            app.LabelpxZ = uilabel(app.UIFigure);
            app.LabelpxXY = uilabel(app.UIFigure);
            app.Instructions = uitextarea(app.UIFigure);
            app.Instructions.Value = {'Bio-formats attempted to import all available metadata in the previous step. However, sometimes information is missing or inaccurate. Double check the values below in "Existing Metadata" and update any innacurate or missing values in the "Manual Override" section. Click "Done" once you''ve confirmed all metadata is correct.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FileDataOverride_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end