classdef FinalCellsExcl_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FullMgTree                  matlab.ui.container.CheckBoxTree
        KeepAllObjects              matlab.ui.control.CheckBox
        Done                        matlab.ui.control.Button
        ListBox                     matlab.ui.control.ListBox
        ObjectsArrangedbySizeLabel  matlab.ui.control.Label
        InstructionPanel            matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        AllObjects                  matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Output
        AllSeparatedObjs
        numObjSep
        FullMg
        FullMgNames
        s
        zs
        voxscale
        SepObjectList
        udSepObjectList
        cmap
        SepObjectListInUnit
        SmCellCutoff
        Remove = false
        Keep = false
    end
    
    methods (Access = public)
        function setup(app, FullCellInput)
      %      app.numObjSep = FullCellInput.numObjSep;
            app.FullMg = FullCellInput.FullMg;
            app.s = FullCellInput.s;
            app.zs = FullCellInput.zs;
            app.voxscale = FullCellInput.voxscale;
            app.SepObjectList = FullCellInput.SepObjectList;
            app.udSepObjectList = FullCellInput.udSepObjectList;
            app.AllSeparatedObjs = FullCellInput.AllSeparatedObjs;

            % Plot all objects
            num = 1:3:(3*app.numObjSep+1);
            fullimg = ones(app.s(1), app.s(2));
            progbar = waitbar(0, 'Plotting...');
        
            for i = 1:numel(app.FullMg)
                waitbar(i/numel(app.FullMg), progbar);
                ex = zeros(app.s(1), app.s(2), app.zs);
                j = app.udSepObjectList(i, 2);
                ex(app.FullMg{1, j}) = 1;
                flatex = sum(ex, 3);
                OutlineImage = zeros(app.s(1), app.s(2));
                OutlineImage(flatex > 1) = 1;
                se = strel('diamond', 4);
                Outline = imdilate(OutlineImage, se);
                fullimg(Outline == 1) = 1;
                fullimg(flatex > 1) = num(i + 1);
            end
        
            if isgraphics(progbar)
                close(progbar);
            end
        
            app.cmap = jet(max(fullimg(:)));
            app.cmap(1, :) = zeros(1, 3);
            imagesc(app.AllObjects, fullimg);
            colormap(app.AllObjects, app.cmap);
            colorbar(app.AllObjects, ...
                'Ticks', [1, 3*app.numObjSep+1], ...
                'TickLabels', {'Small', 'Large'});
            axis(app.AllObjects, 'image');

            app.FullMgNames = fieldnames(app.FullMg);
            MgFields = app.FullMgNames;
            for i = 1:numel(MgFields)
                uitreenode(app.OrigTree, 'Text', MgFields{i});
                expand(app.OrigTree);
            end


            app.SepObjectListInUnit = round(app.SepObjectList(:,1) * app.voxscale);
            app.ListBox.Items = string(app.SepObjectListInUnit(:, 1));
            app.ListBox.ItemsData = 1:size(app.SepObjectList, 1);
        end

        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            rowIndex = app.ListBox.Value;
            ObjectIndex = app.SepObjectList(rowIndex, 2);
            imshow(app.AllSeparatedObjs(:,:,ObjectIndex), 'Parent', app.SelectedObject);
            axis(app.SelectedObject, 'image');
            colormap(app.SelectedObject, app.cmap);
            app.SmCellCutoff = app.SepObjectList(rowIndex, 1);
        end

        % Value changed function: KeepAllObjects
        function KeepAllObjectsValueChanged(app, event)
            Keepbox = app.KeepAllObjects.Value;
            app.Keep = Keepbox;
        end

        % Button pushed function: Done
        function DoneButtonPushed(app, event)
            selected = app.ListBox.Value;
            if numel(selected) < 1
                uialert(app.UIFigure, ...
                    'You must select an object from the list in "Objects Arranged by Size".', ...
                    'Insufficient Selection');
                return;
            end
            
            assignin('base', 'cmap', app.cmap);
            assignin('base', 'SepObjectList', app.SepObjectList);
            assignin('base', 'SmCellCutoff', app.SmCellCutoff);
            assignin('base', 'RemoveXY', app.Remove);
            assignin('base', 'KeepAllCells', app.Keep);
            
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AllObjects = uiaxes(app.UIFigure);
            title(app.AllObjects, 'All Objects')
            app.AllObjects.XTick = [];
            app.AllObjects.YTick = [];
            app.InstructionPanel = uipanel(app.UIFigure);
            app.InstructionPanel.Title = 'Manually Remove Remaining Non-Microglia Objects';
            app.TextArea = uitextarea(app.InstructionPanel);
            app.TextArea.Value = {'Select any remaining objects that are not full microglia and they will be removed.'};
            app.ObjectsArrangedbySizeLabel = uilabel(app.UIFigure);
            app.ObjectsArrangedbySizeLabel.Text = 'Objects Arranged by Size';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Done = uibutton(app.UIFigure, 'push');
            app.Done.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.Done.Text = 'Done';
            app.KeepAllObjects = uicheckbox(app.UIFigure);
            app.KeepAllObjects.ValueChangedFcn = createCallbackFcn(app, @KeepAllObjectsValueChanged, true);
            app.KeepAllObjects.Text = 'Don''t remove any objects';
            app.FullMgTree = uitree(app.UIFigure, 'checkbox');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalCellsExcl_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end