classdef GliaChannel2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SaveCheckBox                  matlab.ui.control.CheckBox
        DoneButton                    matlab.ui.control.Button
        Instructions                  matlab.ui.container.ButtonGroup
        TextArea_2                    matlab.ui.control.TextArea
        TextArea                      matlab.ui.control.TextArea
        InclusionFilterOnlyButton     matlab.ui.control.ToggleButton
        InclusionFilterCombineButton  matlab.ui.control.ToggleButton
        InclusionImg                  matlab.ui.control.UIAxes
        FuseAllImg                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Output
        SaveOverlapImg = true
    end
    
    methods (Access = public)
        
        function setup(app)
            inclimg = imread('IncludeEg.png');
            imshow(inclimg, 'Parent', app.InclusionImg);
            axis(app.InclusionImg, 'image');
                        
            Fuseimg = imread('FuseAllEg.png');
            imshow(Fuseimg, 'Parent', app.FuseAllImg);
            axis(app.FuseAllImg, 'image');  
        end

        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
            app.InclusionFilterOnlyButton.Value = 1;
        end

        % Value changed function: SaveCheckBox
        function SaveCheckBoxValueChanged(app, event)
            SaveBox = app.SaveThreshImg.Value;
            app.SaveOverlapImg = SaveBox;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if app.InclusionFilterOnlyButton.Value
                assignin('base', 'SecondGliaChanChoice', 1);
            end

            if app.InclusionFilterCombineButton.Value
                assignin('base', 'SecondGliaChanChoice', 2);
            end
            
            assignin('base', 'SaveOverlapImg', app.SaveOverlapImg);

            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Instructions = uibuttongroup(app.UIFigure);
            app.Instructions.Title = 'How would you like to combine your 2 glia channels?';
            app.FuseAllImg = uiaxes(app.Instructions);
            app.FuseAllImg.XTick = [];
            app.FuseAllImg.YTick = [];
            app.FuseAllImg.ZTick = [];
            app.InclusionImg = uiaxes(app.Instructions);
            app.InclusionImg.XTick = [];
            app.InclusionImg.YTick = [];
            app.InclusionImg.ZTick = [];
            app.InclusionFilterCombineButton = uitogglebutton(app.Instructions);
            app.InclusionFilterCombineButton.Text = 'Inclusion Filter & Combine';
            app.InclusionFilterOnlyButton = uitogglebutton(app.Instructions);
            app.InclusionFilterOnlyButton.Text = 'Inclusion Filter Only';
            app.InclusionFilterOnlyButton.Value = true;
            app.TextArea = uitextarea(app.Instructions);
            app.TextArea.Value = {'This option will keep signal from your primary glia channel (green circles) only if it overlaps (≥ 1 voxel) with signal from your second glia channel (red triangles). This is useful if your secondary channel is good for filtering (ex. DAPI, transgene reporter) but doesn''t fill the cell well.'};
            app.TextArea_2 = uitextarea(app.Instructions);
            app.TextArea_2.Value = {'This option will filter the same way (≥ 1 voxel overlap) and then fuse overlaping objects from both channels. This is useful if your secondary channel is both more cell-type specific and fills the cell differently (ex. Tmem119, P2RY12).'};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SaveCheckBox = uicheckbox(app.UIFigure);
            app.SaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBoxValueChanged, true);
            app.SaveCheckBox.Text = 'Save image of overlaps';
            app.SaveCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GliaChannel2_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end