classdef ThresholdCh2GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MorphologicalClosingPanel     matlab.ui.container.Panel
        RadiusEditField               matlab.ui.control.NumericEditField
        RadiusEditFieldLabel          matlab.ui.control.Label
        ApplyClosingButton            matlab.ui.control.Button
        FillGapsCheckBox              matlab.ui.control.CheckBox
        PreviewButton                 matlab.ui.control.Button
        NoiseFilterPanel              matlab.ui.container.Panel
        SetCutoffsButton              matlab.ui.control.Button
        NoMaxSizeCheckBox             matlab.ui.control.CheckBox
        NoMinSizeCheckBox             matlab.ui.control.CheckBox
        MaxSize                       matlab.ui.control.NumericEditField
        MaxSizeLabel                  matlab.ui.control.Label
        MinSizeLabel                  matlab.ui.control.Label
        MinSize                       matlab.ui.control.NumericEditField
        SaveOrigImg                   matlab.ui.control.CheckBox
        ViewOrig                      matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        NumberObjects                 matlab.ui.control.NumericEditField
        SaveThreshImg                 matlab.ui.control.CheckBox
        HowManyObjects                matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        ThresholdPanel                matlab.ui.container.Panel
        ThreshModeCheckBox            matlab.ui.control.CheckBox
        ThresholdMethodDropDown       matlab.ui.control.DropDown
        ThresholdMethodDropDownLabel  matlab.ui.control.Label
        ThreshInput                   matlab.ui.control.NumericEditField
        ThreshSlider                  matlab.ui.control.Slider
        SelectedvalueEditFieldLabel   matlab.ui.control.Label
        ThresholdLabel                matlab.ui.control.Label
        SecondaryGliaChannelThesholdSelectionPanel  matlab.ui.container.Panel
        instructions                  matlab.ui.control.Label
        NoiseImg                      matlab.ui.control.UIAxes
        OrigImg                       matlab.ui.control.UIAxes
        ThreshImg                     matlab.ui.control.UIAxes
    end

    % Properties added by user
    properties (Access = public)
        Output
        NoiseIm
        ShowImgs2 = true
        SaveOrig2 = true
        FillGaps2 = false
        FillGapsRadius2
        ThreshMethod2 = false
        ThreshMode2 = false
        BinaryThresholdedImg
        adjustthresh
        setthresh2
        threshlevel2
        adjustnoise
        pxXY
        pxZ
        sizeXY
        length
        imgGliaNorm2
        orig
        voxscale
        SizeFilteredImg2
        SizeFilteredImgMax
        SizeCutoffs struct = struct()
    end

    % Helper functions added by user
    methods (Access = public)
                
        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        
        end
        
        function setup(app, ThreshInput)
            app.orig = ThreshInput.orig;
            app.imgGliaNorm2 = ThreshInput.imgGliaNorm2;
            app.pxXY = ThreshInput.pxXY;
            app.pxZ = ThreshInput.pxZ;
            app.length = ThreshInput.length;
            app.sizeXY = ThreshInput.size;
            app.voxscale = ThreshInput.voxscale;
            
            imshow(ThreshInput.orig, [], 'Parent', app.OrigImg);
            axis(app.OrigImg, 'image');
            app.RadiusEditField.Enable = "off";
            app.FillGapsCheckBox.Enable = "off";
            app.ThreshModeCheckBox.Enable = "off";
            app.ApplyClosingButton.Enable = "off";
            app.MaxSize.Enable = "off";
            app.MinSize.Enable = "off";
            app.NoMaxSizeCheckBox.Value = true;
            app.NoMinSizeCheckBox.Value = true;
        end

        function applyThreshold(app, val)
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Applying Threshold', ...
                'Message', 'Applying Threshold...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off', ...
                'Value', 0);
        
            try
                img3D = app.imgGliaNorm2;
                                
                d.Value = .25;
                drawnow;

                switch app.ThresholdMethodDropDown.Value
                    case '<select method>'
                        close(d);
                        uialert(app.UIFigure, 'Please select a thresholding method first.', 'No Method Selected');
                        return;
                    case 'Otsu'
                        level = graythresh(img3D(:));
                    case 'Multi-Otsu'
                        level = multithresh(img3D(:));
                    case 'Median'
                        level = median(img3D(:));
                    case 'Mean'
                        level = mean(img3D(:));
                end

                if app.ThreshModeCheckBox.Value
                    BinaryImg = zeros(app.sizeXY(1), app.sizeXY(2), app.length);
                    for i = 1:app.length
                        BinaryImg(:, :, i) = imbinarize(img3D(:, :, i), level * val);
                    end
                else
                    BinaryImg = imbinarize(img3D, level * val);
                end
                
                d.Value = .5;
                drawnow;

                if app.FillGapsCheckBox.Value

                   % anisotropic closing
                    app.FillGapsRadius2 = app.RadiusEditField.Value;
                    radiusXY = app.RadiusEditField.Value;

                    % check that radius is not 0 and warn if radius is >5
                    if app.FillGapsRadius2 < 1
                        close(d);
                        uialert(app.UIFigure, 'Fill gaps radius cannot be 0, please select an integer between 1 and 5.', ...
                        'Invalid Size Range');
                        return;
                    elseif app.FillGapsRadius2 > 5
                        uialert(app.UIFigure, ['It is recommended that the fill gaps radius be between 1 and 5. ' ...
                            'If you select a larger integer, processing times may be much longer.'], ...
                        'Long Processing Time Warning');
                    end

                    radiusZ = max(1, round(radiusXY * app.pxXY / app.pxZ));
                    [x, y, z] = meshgrid(-radiusXY:radiusXY, -radiusXY:radiusXY, -radiusZ:radiusZ);
                    se = (x.^2 + y.^2) / radiusXY^2 + z.^2 / radiusZ^2 <= 1;
                    BinaryImg = imclose(BinaryImg, se);
    
                    
                end
                d.Value = 0.75;
                drawnow

                im2D = max(BinaryImg, [], 3);
                imagesc(app.ThreshImg, im2D);
                axis(app.ThreshImg, 'image');
                colormap(app.ThreshImg, gray);

                d.Value = 0.9;
                drawnow;
        
                % app.setthresh = val;
                app.BinaryThresholdedImg = BinaryImg;
                app.threshlevel2 = level;
        
            catch ME
                uialert(app.UIFigure, ME.message, 'Error Applying Threshold');
            end
            
            d.Value = 1;
            drawnow
            close(d)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Button pushed function: ViewOrig
        function ViewOrigPushed(app, event)
           d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Rendering 3D preview', ...
                'Message', 'Rendering 3D preview...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off', ...
                'Value', 0);
                d.Value = .25;
                drawnow;
            viewer = volshow(app.imgGliaNorm2);
                d.Value = .5;
                drawnow;
            fig = ancestor(viewer, 'figure');
                d.Value = .75;
                drawnow;
            fig.Name = sprintf('Original Image');
                d.Value = 1;
                drawnow;
            close(d);
        end

        % Value changed function: ThresholdMethodDropDown
        function ThresholdMethodDropDownValueChanged(app, event)
            method = app.ThresholdMethodDropDown.Value;
            app.ThreshMethod2 = method;
            if isempty(app.setthresh2) || isnan(app.setthresh2)
            else
                val = app.setthresh2;
                applyThreshold(app, val);
            end
            app.FillGapsCheckBox.Enable = "on";
            app.ThreshModeCheckBox.Enable = "on";
        end

        % Value changed function: ThreshSlider
        function ThreshSliderValueChanged(app, event)
            val = round(app.ThreshSlider.Value, 2); 
            app.ThreshInput.Value = val; % Sync the numeric input
            app.setthresh2 = val;
            applyThreshold(app, val); % Apply threshold logic
        end

        % Value changed function: ThreshInput
        function ThreshInputValueChanged(app, event)
            val = round(app.ThreshInput.Value, 2);
            app.ThreshSlider.Value = val; % Sync the slider input
            app.setthresh2 = val;
            applyThreshold(app, val); % Apply threshold logic
        end

        % Value changed function: ThreshModeCheckBox
        function ThreshModeCheckBoxValueChanged(app, event)
            ThreshModeBox = app.ThreshModeCheckBox.Value;
            app.ThreshMode2 = ThreshModeBox;
            val = app.setthresh2;
            applyThreshold(app, val);
        end

        % Value changed function: FillGapsCheckBox
        function FillGapsCheckBoxValueChanged(app, event)
            app.RadiusEditField.Enable = app.FillGapsCheckBox.Value;
            app.ApplyClosingButton.Enable = app.FillGapsCheckBox.Value;
            closing = app.FillGapsCheckBox.Value;
            app.FillGaps2 = closing;
            if app.FillGaps2 == false
                val = app.setthresh2;
                applyThreshold(app, val);
            end
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            val = app.setthresh2;
            applyThreshold(app, val);
        end

        % Button pushed function: ApplyClosingButton
        function ApplyClosingButtonPushed(app, event)
            val = app.setthresh2;
            applyThreshold(app, val);
        end

        % Value changed function: NoMinSizeCheckBox
        function NoMinSizeCheckBoxValueChanged(app, event)
            app.MinSize.Enable = ~app.NoMinSizeCheckBox.Value;
        end

        % Value changed function: NoMaxSizeCheckBox
        function NoMaxSizeCheckBoxValueChanged(app, event)
            app.MaxSize.Enable = ~app.NoMaxSizeCheckBox.Value;
        end

        % Button pushed function: SetCutoffsButton
        function SetCutoffsButtonPushed(app, event)
           d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Set Cutoffs', ...
                'Message', 'Setting Size Cutoffs...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off', ...
                'Value', 0);
            if isempty(app.setthresh2) || isnan(app.setthresh2)
                close(d);
                uialert(app.UIFigure, ['You must specify and set the threshold before setting size filter cutoffs ' ...
                    '(make sure that you have pressed the "Set Threshold" button).'], ...
                    'No Threshold Set');
                return;
            end
                d.Value = .2;
                drawnow;

            % Check if "no min size" is selected
            if app.NoMinSizeCheckBox.Value
                minSize = 0;
            else
                minSize = app.MinSize.Value;
            end
                d.Value = .3;
                drawnow;

            % Check if "no max size" is selected
            if app.NoMaxSizeCheckBox.Value
                maxSize = Inf;
            else
                maxSize = app.MaxSize.Value;
            end
                d.Value = .4;
                drawnow;

            % Validate that min <= max
            if minSize > maxSize
                    close(d);
                uialert(app.UIFigure, 'Minimum size cannot be greater than maximum size.', ...
                        'Invalid Size Range');
                return;
            end
                d.Value = .5;
                drawnow;

            % Store size cutoffs
            app.SizeCutoffs = struct('Min', minSize, 'Max', maxSize);
                d.Value = .6;
                drawnow;

            CC = bwconncomp(app.BinaryThresholdedImg, 26); % Find connected components in 3D
                d.Value = .7;
                drawnow;
            stats = regionprops3(CC, 'Volume'); % Measure voxel count for each object
            volumes = stats.Volume * app.voxscale; % Convert to physical volume
            keepIdx = find(volumes >= app.SizeCutoffs.Min & volumes <= app.SizeCutoffs.Max);
                d.Value = .8;
                drawnow;

            % Create filtered mask
            L = labelmatrix(CC);
            app.SizeFilteredImg2 = ismember(L, keepIdx);
            app.SizeFilteredImgMax = max(app.SizeFilteredImg2, [], 3);
                d.Value = .9;
                drawnow;

            % Show in UIAxes
            imshow(app.SizeFilteredImgMax, 'Parent', app.NoiseImg);
            axis(app.NoiseImg, 'image')
                d.Value = 1;
                drawnow;

            close(d);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Rendering 3D preview', ...
                'Message', 'Rendering 3D preview...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off', ...
                'Value', 0);
            
            % Make sure threshold and is defined and valid
                d.Value = 1;
                drawnow;
            if isempty(app.setthresh2) || isnan(app.setthresh2)
                close(d);
                uialert(app.UIFigure, 'You must specify and set the threshold before viewing a 3D preview of your image ', ...
                    'No Threshold Set');
                return;
            end
                d.Value = .2;
                drawnow;
                            
            % Check that Min/Max Cutoffs are set
            if ~isfield(app.SizeCutoffs, 'Min') || ...
               ~isfield(app.SizeCutoffs, 'Max')
                close(d);
                uialert(app.UIFigure, ['You must specify set size cutoffs before viewing a 3D preview of your image ' ...
                    '(make sure that you have pressed the "Set Size Range Cutoffs" button).'], ...
                        'No Size Cutoffs Set');
                return;
            end
                d.Value = .3;
                drawnow;

            % Show noise filtered 3D image in pop-out window
            PreviewImg3D = uint8(app.SizeFilteredImg2) * 255;
                d.Value = .5;
                drawnow;
            viewer = volshow(PreviewImg3D);
                d.Value = .7;
                drawnow;
            fig = ancestor(viewer, 'figure');
                d.Value = .9;
                drawnow;
            fig.Name = sprintf('Preview: Threshold = %d | Min = %d | Max = %d', ...
                app.setthresh2, app.SizeCutoffs.Min, app.SizeCutoffs.Max);
                d.Value = 1;
                drawnow;
            close(d);
        end

        % Button pushed function: HowManyObjects
        function HowManyObjectsPushed(app, event)
           d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Counting', ...
                'Message', 'Counting objects in 3D...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off', ...
                'Value', 0);
                d.Value = .25;
                drawnow;
            ConnectedComponents = bwconncomp(app.SizeFilteredImg2, 26);
                d.Value = .5;
                drawnow;
            numObj = numel(ConnectedComponents.PixelIdxList);
                d.Value = .75;
                drawnow;
            app.NumberObjects.Value = numObj;
                d.Value = 1;
                drawnow;
            close(d);
        end

        % Value changed function: SaveOrigImg
        function SaveOrigImgValueChanged(app, event)
            Origbox = app.SaveOrigImg.Value;
            app.SaveOrig2 = Origbox;
        end

        % Value changed function: SaveThreshImg
        function SaveThreshImgValueChanged(app, event)
            Threshbox = app.SaveThreshImg.Value;
            app.ShowImgs2 = Threshbox;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if isempty(app.setthresh2) || isnan(app.setthresh2)
                uialert(app.UIFigure, 'You must specify and set the threshold before exiting', ...
                    'No Threshold Set');
                return;
            end

            if ~isfield(app.SizeCutoffs, 'Min') || ...
               ~isfield(app.SizeCutoffs, 'Max')
                uialert(app.UIFigure, ['You must specify set size cutoffs before exiting ' ...
                    '(make sure that you have pressed the "Set Size Range Cutoffs" button).'], ...
                        'No Size Cutoffs Set');
                return;
            end
            
            assignin('base', 'ThreshMethod2', app.ThreshMethod2);
            assignin('base', 'ThreshMode2', app.ThreshMode2);
            assignin('base', 'ShowImg2', app.ShowImgs2);
            assignin('base', 'SaveOrig2', app.SaveOrig2);
            assignin('base', 'NoiseIm2', app.SizeFilteredImg2);
            assignin('base', 'noise2', app.SizeCutoffs);
            assignin('base', 'ThreshSet2', app.setthresh2);
            assignin('base', 'ThreshLevel2', app.threshlevel2);
            assignin('base', 'FillGaps2', app.FillGaps2);
            assignin('base', 'FillGapsRadius2', app.FillGapsRadius2);
            
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThreshImg = uiaxes(app.UIFigure);
            title(app.ThreshImg, 'Thresholded Image')
            app.ThreshImg.XTick = [];
            app.ThreshImg.YTick = [];
            app.ThreshImg.ZTick = [];
            app.OrigImg = uiaxes(app.UIFigure);
            title(app.OrigImg, 'Original Image (Max Projection)')
            app.OrigImg.XTick = [];
            app.OrigImg.YTick = [];
            app.OrigImg.ZTick = [];
            app.NoiseImg = uiaxes(app.UIFigure);
            title(app.NoiseImg, 'Noise Filtered Image')
            app.NoiseImg.XTick = [];
            app.NoiseImg.YTick = [];
            app.NoiseImg.ZTick = [];
            app.SecondaryGliaChannelThesholdSelectionPanel = uipanel(app.UIFigure);
            app.SecondaryGliaChannelThesholdSelectionPanel.Title = 'Secondary Glia Channel Theshold Selection';
            app.instructions = uilabel(app.SecondaryGliaChannelThesholdSelectionPanel);
            app.instructions.Text = {'1) Use the maximum intensity projection of your original image on the left as a reference to help with thresholding. Optional: press ''View original image in 3D'' for volumetric view in a pop-out window.'; '2) Select your preferred thresholding algorithm from the ''Threshold Method" dropdown. Then, select your desired threshold using either the slider or the numeric input field. Optional: you may also choose to apply the threshold to each z-plane separately if preferred. If you want to apply anisotropic closing, click the ''Fill Gaps'' checkbox and input the desired search radius (this is done in voxels). This morphological closing feature can be helpful for bridging gaps between broken branches in your glial structure. Note that computational demand increases with radius; it is recommended to restrict radius to 5 or less.'; '3) Use the ‘Noise Filter’ slider/input to set the minimum and maximum object sizes you’d like to filter out (calculated in 3D). Press ''Set Size Range Cutoffs'' to see the result as a max projection. Optional: press "Preview in 3D" to see your noise filtered image rendered in 3D in a pop-out window. Continue adjusting your threshold and noise filter values until you’re happy. Remember to press ‘Set Size Range Cutoffs’ after making any adjustments.'; '4) Choose whether to save max projections of thresholded/noise filtered image and the original image for reference. Optional: press the ''How many objects is this identifying'' button to see how many 3D objects are detected.'; '5) Press the ''Done'' button when you are satisfied with your thresholded and noise filtered image.'};
            app.ThresholdPanel = uipanel(app.UIFigure);
            app.ThresholdPanel.Title = 'Threshold';
            app.ThresholdLabel = uilabel(app.ThresholdPanel);
            app.ThresholdLabel.Text = 'Threshold Multiplier';
            app.SelectedvalueEditFieldLabel = uilabel(app.ThresholdPanel);
            app.SelectedvalueEditFieldLabel.Text = 'Selected value';
            app.ThreshSlider = uislider(app.ThresholdPanel);
            app.ThreshSlider.MajorTicks = [0 25 50 75 100];
            app.ThreshSlider.ValueChangedFcn = createCallbackFcn(app, @ThreshSliderValueChanged, true);
            app.ThreshSlider.Step = 0.001;
            app.ThreshInput = uieditfield(app.ThresholdPanel, 'numeric');
            app.ThreshInput.Limits = [0 100];
            app.ThreshInput.ValueDisplayFormat = '%.3f';
            app.ThreshInput.ValueChangedFcn = createCallbackFcn(app, @ThreshInputValueChanged, true);
            app.ThresholdMethodDropDownLabel = uilabel(app.ThresholdPanel);
            app.ThresholdMethodDropDownLabel.Text = 'Threshold Method';
            app.ThresholdMethodDropDown = uidropdown(app.ThresholdPanel);
            app.ThresholdMethodDropDown.Items = {'<select method>', 'Otsu', 'Multi-Otsu', 'Median', 'Mean'};
            app.ThresholdMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdMethodDropDownValueChanged, true);
            app.ThresholdMethodDropDown.Value = '<select method>';
            app.ThreshModeCheckBox = uicheckbox(app.ThresholdPanel);
            app.ThreshModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThreshModeCheckBoxValueChanged, true);
            app.ThreshModeCheckBox.Text = 'Apply to z-slices separately';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.HowManyObjects = uibutton(app.UIFigure, 'push');
            app.HowManyObjects.ButtonPushedFcn = createCallbackFcn(app, @HowManyObjectsPushed, true);
            app.HowManyObjects.Text = 'How many objects is this identifying?';
            app.SaveThreshImg = uicheckbox(app.UIFigure);
            app.SaveThreshImg.ValueChangedFcn = createCallbackFcn(app, @SaveThreshImgValueChanged, true);
            app.SaveThreshImg.Text = 'Save filtered image';
            app.SaveThreshImg.Value = true;
            app.NumberObjects = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '#';
            app.ViewOrig = uibutton(app.UIFigure, 'push');
            app.ViewOrig.ButtonPushedFcn = createCallbackFcn(app, @ViewOrigPushed, true);
            app.ViewOrig.Text = 'View original image in 3D';
            app.SaveOrigImg = uicheckbox(app.UIFigure);
            app.SaveOrigImg.ValueChangedFcn = createCallbackFcn(app, @SaveOrigImgValueChanged, true);
            app.SaveOrigImg.Text = 'Save original image';
            app.SaveOrigImg.Value = true;
            app.NoiseFilterPanel = uipanel(app.UIFigure);
            app.NoiseFilterPanel.Title = 'Noise Filter';
            app.MinSize = uieditfield(app.NoiseFilterPanel, 'numeric');
            app.MinSize.Limits = [0 Inf];
            app.MinSizeLabel = uilabel(app.NoiseFilterPanel);
            app.MinSizeLabel.Text = 'Min Size (µm³)';
            app.MaxSizeLabel = uilabel(app.NoiseFilterPanel);
            app.MaxSizeLabel.Text = 'Max Size (µm³)';
            app.MaxSize = uieditfield(app.NoiseFilterPanel, 'numeric');
            app.MaxSize.Limits = [0 Inf];
            app.MaxSize.Value = Inf;
            app.NoMinSizeCheckBox = uicheckbox(app.NoiseFilterPanel);
            app.NoMinSizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoMinSizeCheckBoxValueChanged, true);
            app.NoMinSizeCheckBox.Text = 'No Min Size';
            app.NoMaxSizeCheckBox = uicheckbox(app.NoiseFilterPanel);
            app.NoMaxSizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoMaxSizeCheckBoxValueChanged, true);
            app.NoMaxSizeCheckBox.Text = 'No Max Size';
            app.SetCutoffsButton = uibutton(app.NoiseFilterPanel, 'push');
            app.SetCutoffsButton.ButtonPushedFcn = createCallbackFcn(app, @SetCutoffsButtonPushed, true);
            app.SetCutoffsButton.Text = 'Set Size Range Cutoffs';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview in 3D';
            app.MorphologicalClosingPanel = uipanel(app.UIFigure);
            app.MorphologicalClosingPanel.Title = 'Morphological Closing';
            app.FillGapsCheckBox = uicheckbox(app.MorphologicalClosingPanel);
            app.FillGapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillGapsCheckBoxValueChanged, true);
            app.FillGapsCheckBox.Text = 'Fill Gaps';
            app.ApplyClosingButton = uibutton(app.MorphologicalClosingPanel, 'push');
            app.ApplyClosingButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyClosingButtonPushed, true);
            app.ApplyClosingButton.Text = 'Apply Morphological Closing';
            app.RadiusEditFieldLabel = uilabel(app.MorphologicalClosingPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.MorphologicalClosingPanel, 'numeric');
            app.RadiusEditField.Limits = [0 10];
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThresholdCh2GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end