classdef Welcome_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        WelcomeTextArea       matlab.ui.control.TextArea
        YesButton             matlab.ui.control.Button
        NoButton              matlab.ui.control.Button
        WelcomeTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Output;
        ChoiceContinue;
    end
    
    methods (Access = private)
        
        function centerAppOnScreen(app)
            % Get screen size
            screenSize = get(0, 'ScreenSize');  % [left bottom width height]

            % Get app window size
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
    
            % Calculate centered position
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;

            % Set new position
            app.UIFigure.Position(1:2) = [left, bottom];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.ChoiceContinue = 'Yes';
            app.Output = true;
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.ChoiceContinue = 'No';
            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomeTextAreaLabel = uilabel(app.UIFigure);
            app.WelcomeTextAreaLabel.Text = 'Welcome';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No, I need to install one or more of the required add-ons before proceeding.';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes, I have all required add-ons and am ready to proceed with the script.';
            app.WelcomeTextArea = uitextarea(app.UIFigure);
            app.WelcomeTextArea.Value = {'The present script is designed to analyze the structure of glia in 3D and quantify colocalization with structures in one or more other channels. First, run the present script ("Script1_SingleImage") to generate a parameters file. In this script, you will adjust thresholding parameters, cell-size cutoffs, and colocalization settings (if so desired). Once you have run this script, your parameters will be saved as a .m file and you will be able to use this parameters file to run either of the two batch process scripts, the which process multiple images sequentially. '; ''; 'Note: For batch processing, all images MUST have the same dimensions and scaling factor as those scripts will use all of the same settings specified in the parameters file generated by this single image script.'; ''; 'Before you get started, you must have a the following add-ons installed in MATLAB:'; ''; '    Image Processing Toolbox (MathWorks)'; '    Parallel Computing Toolbox (Mathworks)'; '    Statistics and Machine Learning Toolbox (Mathworks)'; ''; 'If you are missing one or more of these add-ons, click the "No" button and the script will abort. A list of these add-ons will then be displayed in the Command Window for your reference (you can also find them listed at the top of this script). '; ''; 'If you have verified that you have all of the required add-ons installed in MATLAB, click the "Yes" button to proceed with your analysis.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Welcome_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end